classdef Fase1MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Phase1Tab                       matlab.ui.container.Tab
        GrigliaMAIN                     matlab.ui.container.GridLayout
        CyberGloveCalibrationDataPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CyberGloveDataPanel             matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        CybergloveStreamONButton        matlab.ui.control.StateButton
        PositListBox                    matlab.ui.control.ListBox
        PositListBoxLabel               matlab.ui.control.Label
        SavexlsxPositionButton          matlab.ui.control.Button
        PingCyberGloveButton            matlab.ui.control.Button
        Questionario                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RepeatStimulationButton_2       matlab.ui.control.Button
        NextStimulationButton_2         matlab.ui.control.Button
        RepeatStimulationButton         matlab.ui.control.Button
        NextStimulationButton           matlab.ui.control.Button
        DidyousenseatactilesensationAvibrationY1N0Label  matlab.ui.control.Label
        Defineitsspeedfrom1to10Label    matlab.ui.control.Label
        Defineitsstrengthitsvividnessfrom1to10Label  matlab.ui.control.Label
        Phase1GloveStartButton          matlab.ui.control.Button
        Phase1TactorsStartButton        matlab.ui.control.Button
        Phase1GloveFinishButton         matlab.ui.control.Button
        Phase1TactorsFinishButton       matlab.ui.control.Button
        DidyouperceiveasensationofmovementY1N0Label  matlab.ui.control.Label
        ListBox_4                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox                         matlab.ui.control.ListBox
        TactorsPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TimemsLabel                     matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        FreqHzLabel                     matlab.ui.control.Label
        FreqHzKnob                      matlab.ui.control.Knob
        TactorsONPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Tactor3CheckBox                 matlab.ui.control.CheckBox
        Tactor2CheckBox                 matlab.ui.control.CheckBox
        Tactor1CheckBox                 matlab.ui.control.CheckBox
        FIREButton                      matlab.ui.control.Button
        DuratamsKnob                    matlab.ui.control.Knob
        EditFieldDur                    matlab.ui.control.NumericEditField
        EditFieldGain                   matlab.ui.control.NumericEditField
        GainKnob                        matlab.ui.control.Knob
        EditFieldFreq                   matlab.ui.control.NumericEditField
        InitializationPanel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        COMPortsPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PortaListBox                    matlab.ui.control.ListBox
        CommandWindowPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        DisconnectCyberGloveButton      matlab.ui.control.Button
        ConnectCybergloveButton         matlab.ui.control.Button
        InitializeTactorsButton         matlab.ui.control.Button
        UpdateCOMPortsButton            matlab.ui.control.Button
        Fase2Tab                        matlab.ui.container.Tab
        Fase3Tab                        matlab.ui.container.Tab
        Fase4Tab                        matlab.ui.container.Tab
    end

    
    % SavexlsxPositionButtonPushed is currently bugged, review

    properties (Access = private)
        today;
        path;
        glove;
        tab;
        ti;
        com_port;
        DataTags;
        bivariate = true;       % If true we vary randomly frequency AND gain/amplitude in the phase 1 sequence; only frequency otherwise
        answers;
    end
    
    methods (Access = private)

        function KillGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("No CyberGloves connected at the moment");
            end

            try
                clear app.ti
            catch
                disp("No Tactor Interfaces connected at the moment");
            end
        end

        function comwin_on(app) %#ok<MANU> 
            if exist("comwin.txt","file")           % command window puppet creation and handling
                delete("comwin.txt");
            end
            diary comwin.txt
            diary on
        end

        function comwin_off(app)
            diary off
            app.TextArea.Value = regexp(fileread('comwin.txt'), '\r?\n','split');
        end

        function post_it(app,str)
            comwin_on(app)
            disp(str);
            comwin_off(app)
        end

        function vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index)
            devIndex = 0;               % Hard coded until pilot tries
            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isequal(pwd, 'C:\Users\matti\OneDrive\Documenti\SSUP\ScriptsMYKI\MYKI-psychophysics')
                post_it(app,"Avviare dalla cartella giusta! 5... 4... 3...")
                pause(10)
                delete(app)
            end

%             if ~isequal(pwd, 'C:\Users\Mattia Gentile\OneDrive\Documenti\SSUP\ScriptsMYKI\MYKI-psychophysics')
%                 posta(app,"Avviare dalla cartella giusta! 5... 4... 3...")
%                 pause(10)
%                 delete(app)
%             end
            
            app.today = string(datetime('now','Format','dd-MM-yy'));
            app.path = strcat('Fase1_',app.today);
            addpath MATLAB64_TDK-1.0.5.0\

            if ~exist(app.path, 'dir')
                mkdir(app.path);
                addpath(app.path);
            end

            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.com_port = app.PortaListBox.Value;

            if ~exist("DataTags","var")
                if exist("DataTags.mat", "file") ~= 0
                    load DataTags.mat DataTags
                end
            end

            app.DataTags = DataTags; %#ok<ADPROP>         

            app.EditFieldDur.Value = 2000;
            app.EditFieldFreq.Value = 100;
            app.EditFieldGain.Value = 200;

            app.DuratamsKnob.Value = app.EditFieldDur.Value;
            app.FreqHzKnob.Value = app.EditFieldFreq.Value;
            app.GainKnob.Value = app.EditFieldGain.Value;

            app.TextArea.Value = ["Fase 1 Istruzioni:"; "Aggiorna Porte COM prima di connettere Tactors o Cyberglove "; ...
                "Ricordarsi di Disconnettere CyberGlove prima di riprovare con Connetti"; ...
                "Dopo aver connesso Tactors si può Vibrare"; ...
                "Dopo aver connesso CyberGlove si può Interrogare o Streamare";
                "A destra il pannello per le sequenze stimolazioni/misurazioni"];

        end

        % Button pushed function: InitializeTactorsButton
        function InitializeTactorsButtonPushed(app, event)
            comwin_on(app);
            
            app.ti = TactorInterfaceML;
            
            try
                numFound = app.ti.Discover;
                if numFound > 0
                    app.ti.Connect(app.PortaListBox.Value, 1);
                end
            catch
                disp("No Tactor Interface Initialized");
            end

            comwin_off(app);
        end

        % Button pushed function: UpdateCOMPortsButton
        function UpdateCOMPortsButtonPushed(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end

        % Button pushed function: PingCyberGloveButton
        function PingCyberGloveButtonPushed(app, event)
            write(app.glove.serial_p, 'G', 'uint8');
            data = read(app.glove.serial_p, 20, 'uint8');

            if data(20) == 0
                if isempty(app.tab)
                    app.tab = data;
                else
                    app.tab = [app.tab; data];
                end
            end
            
            temp_tab = array2table(app.tab);
            app.UITable.Data = temp_tab;
        end

        % Button pushed function: SavexlsxPositionButton
        function SavexlsxPositionButtonPushed(app, event)
            if ~isa(app.tab, "table")
                app.tab = array2table(app.tab);
                app.tab.Properties.VariableNames(1:20) = app.DataTags;
            end

            % There's a bug here, can't save both positions, need a new
            % button to erase current data in app.tab and it's k
            
            writetable(app.tab,strcat(app.path,"\","cal_", app.PositListBox.Value,"_",app.today,".xlsx"));
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            try
                freq = round(10 * app.FreqHzKnob.Value);                        % ChangeFreq wants frequency in tens of Hz
                gain = round(app.GainKnob.Value);
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
                pulse_delta = round(app.DuratamsKnob.Value);
                delay = pulse_delta / 10 - 1;                                   % delay must be in centiseconds

                vibrateTactors(app,freq,gain,pulse_delta,delay,vib_index)
            catch
                post_it(app,"Non ci sono i Tactors collegati!!!");
            end
        end

        % Button pushed function: ConnectCybergloveButton
        function ConnectCybergloveButtonPushed(app, event)
            comwin_on(app);

            try
                app.com_port = app.PortaListBox.Value;
                app.glove = Cyberglove(app.com_port);
            catch
                disp("CONNESSIONE CYBERGLOVE FALLITA!!!")
            end

            comwin_off(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            KillGlove(app)

            if isfile("comwin.txt")
                delete("comwin.txt");
            end

            delete(app)            
        end

        % Button pushed function: DisconnectCyberGloveButton
        function DisconnectCyberGloveButtonPushed(app, event)
            comwin_on(app);
            KillGlove(app);
            comwin_off(app);
        end

        % Value changed function: EditFieldFreq
        function EditFieldFreqValueChanged(app, event)
            value = app.EditFieldFreq.Value;
            app.FreqHzKnob.Value = value;
        end

        % Value changed function: EditFieldGain
        function EditFieldGainValueChanged(app, event)
            value = app.EditFieldGain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: EditFieldDur
        function EditFieldDurValueChanged(app, event)
            value = app.EditFieldDur.Value;
            app.DuratamsKnob.Value = value;
        end

        % Value changed function: FreqHzKnob
        function FreqHzKnobValueChanged(app, event)
            value = app.FreqHzKnob.Value;
            app.EditFieldFreq.Value = value;
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = app.GainKnob.Value;
            app.EditFieldGain.Value = value;
        end

        % Value changed function: DuratamsKnob
        function DuratamsKnobValueChanged(app, event)
            value = app.DuratamsKnob.Value;
            app.EditFieldDur.Value = value;
        end

        % Value changed function: CybergloveStreamONButton
        function CybergloveStreamONButtonValueChanged(app, event)
            % stream_on = app.CybergloveStreamONButton.Value;
            % https://it.mathworks.com/help/matlab/creating_guis/wind-speed-gui-in-app-designer.html
            % Qui devo usare la funzione ReadData da cybercoso.m

        end

        % Button pushed function: Phase1TactorsStartButton
        function Phase1TactorsStartButtonPushed(app, event)
            try
                app.NextStimulationButton.Enable = "on";
                app.RepeatStimulationButton.Enable = "on";
                app.Phase1TactorsFinishButton.Enable = "on";

                post_it(app,["La sequenza di stimolazioni-misurazioni inizierà quando verrà premuto il tasto Invia Prossima Stimolazione";
                    "Il numero di stimolazioni non è vincolato, e la sequenza continuerà fino alla pressione del pulsante Salva Fase 1 - Tactor";
                    "Per ogni stimolazione Frequenza e Gain vengono scelti randomicamente,";
                    "mentre Durata e #Tactor vengono controllati dal pannello Tactors in basso.";
                    "Default sequenza bivariata, si può cambiare nel codice"]);


                freq = 10 * (70 + 5 * unidrnd(12));
                % Frequency is pulled from a discrete uniform distribution, from 70 to 130Hz, with 5Hz increments
                if app.bivariate
                    gain = 50 + 10 * unidrnd(20,20,1);
                    % Gain is pulled from a discrete uniform
                    % distribution, from 50 to 250, with increments
                    % of 10
                else
                    gain = 200;
                    % Gain is fixed, chosen as "strong but not too strong"
                end
                app.EditFieldFreq.Value = freq;
                app.EditFieldGain.Value = gain;
                pulse_delta = app.EditFieldDur.Value;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);

                app.Phase1TactorsStartButton.Enable = "off";
            catch
                post_it(app,"Tactors not connected properly, please retry")
            end
        end

        % Button pushed function: RepeatStimulationButton
        function RepeatStimulationButtonPushed(app, event)
            % This time we get the vibration values straight from the knobs

            freq = 10 * app.EditFieldFreq.Value;
            gain = app.EditFieldGain.Value;
            pulse_delta = app.EditFieldDur.Value;
            delay = pulse_delta / 10 - 1;
            vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

            vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
        end

        % Button pushed function: NextStimulationButton
        function NextStimulationButtonPushed(app, event)
            % We save the current answers and execute the next random vibration
            
            app.answers = [app.answers; app.EditFieldFreq.Value app.EditFieldGain.Value app.ListBox.Value app.ListBox_2.Value app.ListBox_3.Value app.ListBox_4.Value];

            freq = 10 * (70 + 5 * unidrnd(12));
            % Frequency is pulled from a discrete uniform distribution, from 70 to 130Hz, with 5Hz increments
            if app.bivariate
                gain = 50 + 10 * unidrnd(20,20,1);
                % Gain is pulled from a discrete uniform
                % distribution, from 50 to 250, with increments
                % of 10
            else
                gain = 200;
                % Gain is fixed, chosen as "strong but not too strong"
            end
            app.EditFieldFreq.Value = freq;         
            app.EditFieldGain.Value = gain;
            pulse_delta = app.EditFieldDur.Value;
            delay = pulse_delta / 10 - 1;
            vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

            vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
        end

        % Button pushed function: Phase1TactorsFinishButton
        function Phase1TactorsFinishButtonPushed(app, event)
            if isfile(strcat(app.path, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"))
                post_it(app,"Answers file already present, please rename it to save it or delete it.")
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:6) = ["Freq" "Gain" "Answer 1" "Answer 2" "Answer 3" "Answer 4"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"));
                pause(1);
                app.NextStimulationButton.Enable = "off";
                app.RepeatStimulationButton.Enable = "off";
                app.Phase1TactorsFinishButton.Enable = "off";
                app.Phase1TactorsStartButton.Enable = "on";
            end
        end

        % Button pushed function: Phase1GloveStartButton
        function Phase1GloveStartButtonPushed(app, event)
            try
                post_it(app,["La sequenza di stimolazioni-misurazioni inizierà quando verrà premuto il tasto Invia Prossima Stimolazione";
                    "Il numero di stimolazioni non è vincolato, e la sequenza continuerà fino alla pressione del pulsante Salva Fase 1 - Tactor";
                    "Per ogni stimolazione Frequenza e Gain vengono scelti randomicamente,";
                    "mentre Durata e #Tactor vengono controllati dal pannello Tactors in basso.";
                    "Default sequenza bivariata, si può cambiare nel codice"; ...
                    "PLACEHOLDER COPIATO DA SEQ. TACTS."]);

                % qui va inserita la sequenza da mandare ai driver Myki.....

            catch
                post_it(app,"Tactors not connected properly, please retry")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'F1MYKI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1Tab = uitab(app.TabGroup);
            app.Phase1Tab.Title = 'Phase 1';
            app.GrigliaMAIN = uigridlayout(app.Phase1Tab);
            app.InitializationPanel = uipanel(app.GrigliaMAIN);
            app.InitializationPanel.Title = 'Initialization';
            app.GridLayout3 = uigridlayout(app.InitializationPanel);
            app.UpdateCOMPortsButton = uibutton(app.GridLayout3, 'push');
            app.UpdateCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCOMPortsButtonPushed, true);
            app.UpdateCOMPortsButton.Text = {'Update'; 'COM Ports'};
            app.InitializeTactorsButton = uibutton(app.GridLayout3, 'push');
            app.InitializeTactorsButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeTactorsButtonPushed, true);
            app.InitializeTactorsButton.Text = {'Initialize'; 'Tactors'};
            app.ConnectCybergloveButton = uibutton(app.GridLayout3, 'push');
            app.ConnectCybergloveButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCybergloveButtonPushed, true);
            app.ConnectCybergloveButton.Text = {'Connect'; 'Cyberglove'};
            app.DisconnectCyberGloveButton = uibutton(app.GridLayout3, 'push');
            app.DisconnectCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectCyberGloveButtonPushed, true);
            app.DisconnectCyberGloveButton.Text = {'Disconnect'; 'CyberGlove'};
            app.CommandWindowPanel = uipanel(app.GridLayout3);
            app.CommandWindowPanel.Title = 'Command Window';
            app.GridLayout9 = uigridlayout(app.CommandWindowPanel);
            app.TextArea = uitextarea(app.GridLayout9);
            app.COMPortsPanel = uipanel(app.GridLayout3);
            app.COMPortsPanel.Title = 'COM Ports';
            app.GridLayout8 = uigridlayout(app.COMPortsPanel);
            app.PortaListBox = uilistbox(app.GridLayout8);
            app.PortaListBox.Items = {};
            app.PortaListBox.Value = {};
            app.TactorsPanel = uipanel(app.GrigliaMAIN);
            app.TactorsPanel.Title = 'Tactors';
            app.GridLayout2 = uigridlayout(app.TactorsPanel);
            app.EditFieldFreq = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldFreq.Limits = [60 160];
            app.EditFieldFreq.ValueDisplayFormat = '%.0f';
            app.EditFieldFreq.ValueChangedFcn = createCallbackFcn(app, @EditFieldFreqValueChanged, true);
            app.EditFieldFreq.Value = 60;
            app.GainKnob = uiknob(app.GridLayout2, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.Value = 50;
            app.EditFieldGain = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldGain.Limits = [50 255];
            app.EditFieldGain.ValueDisplayFormat = '%.0f';
            app.EditFieldGain.ValueChangedFcn = createCallbackFcn(app, @EditFieldGainValueChanged, true);
            app.EditFieldGain.Value = 50;
            app.EditFieldDur = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldDur.Limits = [100 5000];
            app.EditFieldDur.ValueDisplayFormat = '%.0f';
            app.EditFieldDur.ValueChangedFcn = createCallbackFcn(app, @EditFieldDurValueChanged, true);
            app.EditFieldDur.Value = 100;
            app.DuratamsKnob = uiknob(app.GridLayout2, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.ValueChangedFcn = createCallbackFcn(app, @DuratamsKnobValueChanged, true);
            app.DuratamsKnob.Value = 100;
            app.FIREButton = uibutton(app.GridLayout2, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE';
            app.TactorsONPanel = uipanel(app.GridLayout2);
            app.TactorsONPanel.Title = 'Tactors ON';
            app.GridLayout6 = uigridlayout(app.TactorsONPanel);
            app.Tactor1CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor1CheckBox.Text = 'Tactor 1';
            app.Tactor2CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor2CheckBox.Text = 'Tactor 2';
            app.Tactor3CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor3CheckBox.Text = 'Tactor 3';
            app.FreqHzKnob = uiknob(app.GridLayout2, 'continuous');
            app.FreqHzKnob.Limits = [60 160];
            app.FreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqHzKnobValueChanged, true);
            app.FreqHzKnob.Value = 60;
            app.FreqHzLabel = uilabel(app.GridLayout2);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.GainLabel = uilabel(app.GridLayout2);
            app.GainLabel.Text = 'Gain';
            app.TimemsLabel = uilabel(app.GridLayout2);
            app.TimemsLabel.Text = 'Time (ms)';
            app.Questionario = uipanel(app.GrigliaMAIN);
            app.Questionario.Title = 'Phase 1 Stimulations and Questionnaires';
            app.GridLayout7 = uigridlayout(app.Questionario);
            app.ListBox = uilistbox(app.GridLayout7);
            app.ListBox.Items = {'0', '1'};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = '0';
            app.ListBox_2 = uilistbox(app.GridLayout7);
            app.ListBox_2.Items = {'0', '1'};
            app.ListBox_2.Enable = 'off';
            app.ListBox_2.Value = '0';
            app.ListBox_3 = uilistbox(app.GridLayout7);
            app.ListBox_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_3.Enable = 'off';
            app.ListBox_3.Value = '5';
            app.ListBox_4 = uilistbox(app.GridLayout7);
            app.ListBox_4.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_4.Enable = 'off';
            app.ListBox_4.Value = '5';
            app.DidyouperceiveasensationofmovementY1N0Label = uilabel(app.GridLayout7);
            app.DidyouperceiveasensationofmovementY1N0Label.Text = 'Did you perceive a sensation of movement? - Y: 1, N: 0';
            app.Phase1TactorsFinishButton = uibutton(app.GridLayout7, 'push');
            app.Phase1TactorsFinishButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsFinishButtonPushed, true);
            app.Phase1TactorsFinishButton.Enable = 'off';
            app.Phase1TactorsFinishButton.Text = 'Phase 1 Tactors - Finish';
            app.Phase1GloveFinishButton = uibutton(app.GridLayout7, 'push');
            app.Phase1GloveFinishButton.Enable = 'off';
            app.Phase1GloveFinishButton.Text = 'Phase 1 Glove - Finish';
            app.Phase1TactorsStartButton = uibutton(app.GridLayout7, 'push');
            app.Phase1TactorsStartButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsStartButtonPushed, true);
            app.Phase1TactorsStartButton.Text = 'Phase 1 Tactors - Start';
            app.Phase1GloveStartButton = uibutton(app.GridLayout7, 'push');
            app.Phase1GloveStartButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1GloveStartButtonPushed, true);
            app.Phase1GloveStartButton.Text = 'Phase 1 Glove - Start';
            app.Defineitsstrengthitsvividnessfrom1to10Label = uilabel(app.GridLayout7);
            app.Defineitsstrengthitsvividnessfrom1to10Label.Text = 'Define its strength, its vividness, from 1 to 10.';
            app.Defineitsspeedfrom1to10Label = uilabel(app.GridLayout7);
            app.Defineitsspeedfrom1to10Label.Text = 'Define its speed, from 1 to 10.';
            app.DidyousenseatactilesensationAvibrationY1N0Label = uilabel(app.GridLayout7);
            app.DidyousenseatactilesensationAvibrationY1N0Label.Text = 'Did you sense a tactile sensation? A vibration? - Y: 1, N: 0';
            app.NextStimulationButton = uibutton(app.GridLayout7, 'push');
            app.NextStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NextStimulationButtonPushed, true);
            app.NextStimulationButton.Enable = 'off';
            app.NextStimulationButton.Text = 'Next Stimulation';
            app.RepeatStimulationButton = uibutton(app.GridLayout7, 'push');
            app.RepeatStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimulationButtonPushed, true);
            app.RepeatStimulationButton.Enable = 'off';
            app.RepeatStimulationButton.Text = 'Repeat Stimulation';
            app.NextStimulationButton_2 = uibutton(app.GridLayout7, 'push');
            app.NextStimulationButton_2.Enable = 'off';
            app.NextStimulationButton_2.Text = 'Next Stimulation';
            app.RepeatStimulationButton_2 = uibutton(app.GridLayout7, 'push');
            app.RepeatStimulationButton_2.Enable = 'off';
            app.RepeatStimulationButton_2.Text = 'Repeat Stimulation';
            app.CyberGloveCalibrationDataPanel = uipanel(app.GrigliaMAIN);
            app.CyberGloveCalibrationDataPanel.Title = 'CyberGlove Calibration Data';
            app.GridLayout4 = uigridlayout(app.CyberGloveCalibrationDataPanel);
            app.PingCyberGloveButton = uibutton(app.GridLayout4, 'push');
            app.PingCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @PingCyberGloveButtonPushed, true);
            app.PingCyberGloveButton.Text = {'Ping'; 'CyberGlove'};
            app.SavexlsxPositionButton = uibutton(app.GridLayout4, 'push');
            app.SavexlsxPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavexlsxPositionButtonPushed, true);
            app.SavexlsxPositionButton.Text = {'Save .xlsx'; 'Position'};
            app.PositListBoxLabel = uilabel(app.GridLayout4);
            app.PositListBoxLabel.Text = 'Posit.';
            app.PositListBox = uilistbox(app.GridLayout4);
            app.PositListBox.Items = {'Flex', 'Ext'};
            app.PositListBox.Value = 'Flex';
            app.CybergloveStreamONButton = uibutton(app.GridLayout4, 'state');
            app.CybergloveStreamONButton.ValueChangedFcn = createCallbackFcn(app, @CybergloveStreamONButtonValueChanged, true);
            app.CybergloveStreamONButton.Text = 'Cyberglove Stream ON';
            app.CyberGloveDataPanel = uipanel(app.GridLayout4);
            app.CyberGloveDataPanel.Title = 'CyberGlove Data';
            app.GridLayout10 = uigridlayout(app.CyberGloveDataPanel);
            app.UITable = uitable(app.GridLayout10);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Fase2Tab = uitab(app.TabGroup);
            app.Fase2Tab.Title = 'Fase 2';
            app.Fase3Tab = uitab(app.TabGroup);
            app.Fase3Tab.Title = 'Fase 3';
            app.Fase4Tab = uitab(app.TabGroup);
            app.Fase4Tab.Title = 'Fase 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fase1MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end