classdef MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Phase1TrainingandCalibrationTab  matlab.ui.container.Tab
        GrigliaMAIN                     matlab.ui.container.GridLayout
        CyberGloveCalibrationDataPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CyberGloveDataPanel             matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        CybergloveStreamONmissingButton  matlab.ui.control.StateButton
        PositListBox                    matlab.ui.control.ListBox
        PositListBoxLabel               matlab.ui.control.Label
        SavexlsxPositionButton          matlab.ui.control.Button
        PingCyberGloveButton            matlab.ui.control.Button
        Questionario                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RepeatStimulationButton_2       matlab.ui.control.Button
        NextStimulationButton_2         matlab.ui.control.Button
        RepeatStimulationButton         matlab.ui.control.Button
        NextStimulationButton           matlab.ui.control.Button
        DidyousenseatactilesensationAvibrationY1N0Label  matlab.ui.control.Label
        Defineitsspeedfrom1to10Label    matlab.ui.control.Label
        Defineitsstrengthitsvividnessfrom1to10Label  matlab.ui.control.Label
        Phase1GloveStartButton          matlab.ui.control.Button
        Phase1TactorsStartButton        matlab.ui.control.Button
        Phase1GloveFinishButton         matlab.ui.control.Button
        Phase1TactorsFinishButton       matlab.ui.control.Button
        DidyouperceiveasensationofmovementY1N0Label  matlab.ui.control.Label
        ListBox_4                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox                         matlab.ui.control.ListBox
        TactorsPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TimemsLabel                     matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        FreqHzLabel                     matlab.ui.control.Label
        FreqHzKnob                      matlab.ui.control.Knob
        TactorsONPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Tactor3CheckBox                 matlab.ui.control.CheckBox
        Tactor2CheckBox                 matlab.ui.control.CheckBox
        Tactor1CheckBox                 matlab.ui.control.CheckBox
        FIREButton                      matlab.ui.control.Button
        DuratamsKnob                    matlab.ui.control.Knob
        EditFieldDur                    matlab.ui.control.NumericEditField
        EditFieldGain                   matlab.ui.control.NumericEditField
        GainKnob                        matlab.ui.control.Knob
        EditFieldFreq                   matlab.ui.control.NumericEditField
        InitializationPanel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        COMPortsPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PortaListBox                    matlab.ui.control.ListBox
        CommandWindowPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        DisconnectCyberGloveButton      matlab.ui.control.Button
        ConnectCybergloveButton         matlab.ui.control.Button
        InitializeTactorsButton         matlab.ui.control.Button
        UpdateCOMPortsButton            matlab.ui.control.Button
        Phase2AdaptiveThresholdsTab     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SaveDataPanel                   matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SavebothDataandImageButton      matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        OutputGraphsPanel               matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        InitializeCommandWindowPanel    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        MoreIntenseButton               matlab.ui.control.Button
        ShowDirectoriesButton           matlab.ui.control.Button
        SameIntensityButton             matlab.ui.control.Button
        LessIntenseButton               matlab.ui.control.Button
        StartSequenceButton             matlab.ui.control.Button
        CreateTrialDirectoryButton      matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TrialVariableDropDown           matlab.ui.control.DropDown
        TrialVariableDropDownLabel      matlab.ui.control.Label
        ThisTrialNEditField             matlab.ui.control.NumericEditField
        ThisTrialNEditFieldLabel        matlab.ui.control.Label
        Phase3Tab                       matlab.ui.container.Tab
        Phase4Tab                       matlab.ui.container.Tab
    end

    properties (Access = private)
        today;
        path_1;
        path_2;
        
        % Phase 1 Properties
        glove;
        glove_cal_pulls;
        glove_freq;
        glove_ampl;
        ti;
        com_port;
        DataTags;
        bivariate = true;       % If true we vary randomly frequency AND gain/amplitude in the phase 1 sequence; only frequency otherwise
        answers;

        % Phase 2 properties
        n_trial;
        this_trial_path;
        c;
        m;
        Z;
        Z_old;
        phi;
        var;
        same = 0;
        f_interval = 5;
        % this is the interval in which frequency must stay to enable
        % the sequence termination
        a_interval = 3;
        % this is the interval in which amplitude/torque must stay to enable
        % the sequence termination
        s_cutoff = 10;
        % this is the number of stimulations in which the VAR must stay to
        % enable the sequence termination
    end
    
    methods (Access = private)

        function KillGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("No CyberGloves connected at the moment");
            end

            try
                clear app.ti
            catch
                disp("No Tactor Interfaces connected at the moment");
            end
        end

        function comwin_on(app) %#ok<MANU> 
            if exist("comwin.txt","file")           % command window puppet creation and handling
                delete("comwin.txt");
            end
            diary comwin.txt
            diary on
        end

        function comwin_off(app)
            diary off
            app.TextArea.Value = regexp(fileread('comwin.txt'), '\r?\n','split');
            app.TextArea_2.Value = app.TextArea.Value;
        end

        function post_it(app,str,append)
            if nargin < 3
                append = false;
            end
            if ~append
                comwin_on(app)
                disp(str);
                comwin_off(app)
            else
                % If append argument is true, skip the deleting of previous
                % text output into command window
                diary comwin.txt
                diary on
                disp(str);
                comwin_off(app)
            end
        end

        function vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index)
            devIndex = 0;               % Hard coded until pilot trials
            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
        end

        function adaptive_sequence(app)
            switch length(app.var)
                case 0
                    if app.TrialVariableDropDown.Value == "Frequency"
                        app.c = 50;
                        % empirically chosen constant
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.phi = 0.8;
                        % confidence of the achieved threshold result
                        app.var = 50;
                        % starting frequency value, empirically chosen
                        app.same = 0;
                        % for redundance
                        app.var = app.var + app.c / (1 + app.m) * (app.Z - app.phi);
                        % formula for ascending threshold
                        app.TextArea_2.Value = [strcat("Deliver MYKI stimulation with Frequency of ", num2str(app.var(end)), " and fixed Torque"); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons"];
                    elseif app.TrialVariableDropDown.Value == "Ampl_Torque"
                        app.c = 50;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.phi = 0.8;
                        % confidence of the achieved threshold result
                        app.var = 40;
                        % starting torque value, empirically chosen TO BE
                        % DETERMINED
                        app.same = 0;
                        % for redundance
                        app.var = app.var - app.c / (1 + app.m) * (app.Z - app.phi); % 40 - 15/1*(1-0.8)
                        % formula for descending threshold
                        app.TextArea_2.Value = [strcat("Deliver MYKI stimulation with Torque of ", num2str(app.var(end)), " and fixed Frequency"); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons"];
                    end
                    app.StartSequenceButton.Text = "Deliver Second Stimulation";
                    app.MoreIntenseButton.Enable = "off";
                    app.LessIntenseButton.Enable = "off";
                    app.SameIntensityButton.Enable = "off";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                case 1
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Ampl_Torque"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                    end
                    app.var = [app.var new_var];
                    app.TextArea_2.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                        "Please express a comparison between this kinesthetic illusion and the last one";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer"];
                    app.StartSequenceButton.Text = "Sequence Ongoing";
                    app.StartSequenceButton.Enable = "off";
                    app.MoreIntenseButton.Enable = "on";
                    app.LessIntenseButton.Enable = "on";
                    app.SameIntensityButton.Enable = "on";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                otherwise
                    if app.Z ~= app.Z_old
                        app.m = app.m + 1;
                    end
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Ampl_Torque"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                    end
                    app.var = [app.var new_var];
                    app.TextArea_2.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                        "Please express a comparison between this kinesthetic illusion and the last one";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer";
                        "---";
                        "Now the sequence will keep going with each comparison answered!"];
                    if length(app.var) > 15
                        switch app.TrialVariableDropDown.Value
                            case "Frequency"
                                interval = app.f_interval;                                  % These intervals are chosen as intervals of non intellegible difference (meeting)
                            case "Ampl_Torque"
                                interval = app.a_interval;
                        end
                        if max(app.var(end-app.s_cutoff:end)) - min(app.var(end-app.s_cutoff:end)) <= interval
                            app.TextArea_2.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed"); "---";
                                "Please express a comparison between this kinesthetic illusion and the last one";
                                "---";
                                "Was it more intense? Less intense? Or the same intensity?";
                                "---";
                                "Use the buttons on the right to answer";
                                "---";
                                "Now the sequence will keep going with each comparison answered!";
                                "---";
                                strcat("THE LAST ", num2str(app.s_cutoff), " STIMULATIONS ARE WITHIN THE SEQUENCE TERMINATION INTERVAL OF ", num2str(interval));
                                "YOU CAN EXIT THE SEQUENCE BY SAVING WITH BUTTONS BELOW!"];
                            app.SaveDataButton.Enable = "on";
                            app.SaveImageButton.Enable = "on";
                            app.SavebothDataandImageButton.Enable = "on";
                        end
                    end                               
                    app.Z_old = app.Z;
            end
            draw_sequence(app)
        end

        function draw_sequence(app)
            if app.TrialVariableDropDown.Value == "Frequency"
                title(app.UIAxes, 'Frequency Adaptive Sequence');
                app.UIAxes.YLim = [0 max(app.var)+10];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Ampl_Torque"
                title(app.UIAxes, 'Current Ampl / Torque Adaptive Sequence');
                app.UIAxes.YLim = [min(app.var)-5 40];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            end
            app.UIAxes.XLim = [0 length(app.var)+1];
            plot(app.UIAxes,length(app.var),app.var(end),"r","Marker","hexagram","MarkerSize",10)
        end

        function save_phase2(app, data, image)
            if data == true
                % Save only the data gathered
                writematrix(app.var, strcat(app.this_trial_path, "\Phase2_Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value, "_Data.xlsx"));
            end
            if image == true
                % Save the image in png format
                figure;
                % Create new axis
                newAxes = axes;
                % Copy all objects from UIAxes to new axis
                newAxes.Title= app.UIAxes.Title;
                newAxes.XLabel = app.UIAxes.XLabel;
                newAxes.YLabel = app.UIAxes.YLabel;
                grid(newAxes,'on');
                copyobj(app.UIAxes.Children, newAxes)
                % in order to keep titles and labels on uiaxes
                xlabel(app.UIAxes, newAxes.XLabel.String);
                ylabel(app.UIAxes, newAxes.YLabel.String);
                title(app.UIAxes, newAxes.Title.String);
                fig2Save = ancestor(newAxes, 'figure');
                fig2Save.Visible='off';
                saveas(fig2Save, strcat(app.this_trial_path, "\Phase2_Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value, "_Image.png"));
            end
            app.TextArea_2.Value = "File(s) saved. Now more data can be gathered in 3... 2...";
            pause(3);
            % resetting state as new
            app.StartSequenceButton.Enable = "on";
            app.StartSequenceButton.Text = "Start Sequence";
            app.MoreIntenseButton.Enable = 0;
            app.LessIntenseButton.Enable = 0;
            app.SameIntensityButton.Enable = 0;
            app.ThisTrialNEditField.Enable = 1;
            app.TrialVariableDropDown.Enable = 1;
            app.CreateTrialDirectoryButton.Enable = 1;
            app.SaveDataButton.Enable = 0;
            app.SaveImageButton.Enable = 0;
            app.SavebothDataandImageButton.Enable = 0;
            app.var = [];
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes,"off");
            Phase2AdaptiveThresholdsTabButtonDown(app)
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd('C:\Users\matti\OneDrive\Documenti\SSUP\ScriptsMYKI\MYKI-psychophysics');
            % INSERT HERE THE WORK PATH
            
            app.today = string(datetime('now','Format','dd-MM-yy'));
            app.path_1 = strcat('Phase1_',app.today);
            app.path_2 = strcat('Phase2_',app.today);
            addpath MATLAB64_TDK-1.0.5.0\

            if exist(app.path_1, 'dir')
                post_it(app, "Found todays Phase 1 directory",true)
                addpath(app.path_1)
            end

            if exist(strcat('Phase2_', app.today),"dir")
                post_it(app, 'Found todays Phase 2 directory', true)
                addpath(app.path_2)
                % inserire caricamento del precedente trial number
            end

            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.com_port = app.PortaListBox.Value;

            if ~exist("DataTags","var")
                if exist("DataTags.mat", "file") ~= 0
                    load DataTags.mat DataTags
                end
            end

            app.DataTags = DataTags; %#ok<ADPROP>         
    
            app.EditFieldDur.Value = 2000;
            app.EditFieldFreq.Value = 100;
            app.EditFieldGain.Value = 200;

            app.DuratamsKnob.Value = app.EditFieldDur.Value;
            app.FreqHzKnob.Value = app.EditFieldFreq.Value;
            app.GainKnob.Value = app.EditFieldGain.Value;

            app.TextArea.Value = ["Fase 1 Istruzioni:"; "Aggiorna Porte COM prima di connettere Tactors o Cyberglove "; ...
                "Ricordarsi di Disconnettere CyberGlove prima di riprovare con Connetti"; ...
                "Dopo aver connesso Tactors si può Vibrare"; ...
                "Dopo aver connesso CyberGlove si può Interrogare o Streamare";
                "A destra il pannello per le sequenze stimolazioni/misurazioni"];

        end

        % Button pushed function: InitializeTactorsButton
        function InitializeTactorsButtonPushed(app, event)
            comwin_on(app);
            
            app.ti = TactorInterfaceML;
            
            try
                numFound = app.ti.Discover;
                if numFound > 0
                    app.ti.Connect(app.PortaListBox.Value, 1);
                end
            catch
                disp("No Tactor Interface Initialized");
            end

            comwin_off(app);
        end

        % Button pushed function: UpdateCOMPortsButton
        function UpdateCOMPortsButtonPushed(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end

        % Button pushed function: PingCyberGloveButton
        function PingCyberGloveButtonPushed(app, event)
            write(app.glove.serial_p, 'G', 'uint8');
            data = read(app.glove.serial_p, 20, 'uint8');

            if data(20) == 0
                if isempty(app.glove_cal_pulls)
                    app.glove_cal_pulls = data;
                else
                    app.glove_cal_pulls = [app.glove_cal_pulls; data];
                end
            end
            
            temp_tab = array2table(app.glove_cal_pulls);
            app.UITable.Data = temp_tab;
        end

        % Button pushed function: SavexlsxPositionButton
        function SavexlsxPositionButtonPushed(app, event)
            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end
            
            if ~isa(app.glove_cal_pulls, "table")
                app.glove_cal_pulls = array2table(app.glove_cal_pulls);
                app.glove_cal_pulls.Properties.VariableNames(1:20) = app.DataTags;
            end
            
            writetable(app.glove_cal_pulls,strcat(app.path_1,"\","cal_", app.PositListBox.Value,"_",app.today,".xlsx"),"WriteMode","append");

            app.glove_cal_pulls = [];
            app.UITable.Data = table();

            % This way if the file is already there it adds lines of data
            % on the bottom, if it doesn't exist it creates anew.
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            try
                freq = round(10 * app.FreqHzKnob.Value);                        % ChangeFreq wants frequency in tens of Hz
                gain = round(app.GainKnob.Value);
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
                pulse_delta = round(app.DuratamsKnob.Value);
                delay = pulse_delta / 10 - 1;                                   % delay must be in centiseconds

                vibrateTactors(app,freq,gain,pulse_delta,delay,vib_index)
            catch
                post_it(app,"NO TACTORS CONNECTED NOR INITIALIZED!!!");
            end
        end

        % Button pushed function: ConnectCybergloveButton
        function ConnectCybergloveButtonPushed(app, event)
            comwin_on(app);

            try
                app.com_port = app.PortaListBox.Value;
                app.glove = Cyberglove(app.com_port);
            catch
                disp("CYBERGLOVE CONNECTION FAILED!!!")
            end

            comwin_off(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            KillGlove(app)

            if isfile("comwin.txt")
                delete("comwin.txt");
            end

            delete(app)            
        end

        % Button pushed function: DisconnectCyberGloveButton
        function DisconnectCyberGloveButtonPushed(app, event)
            comwin_on(app);
            KillGlove(app);
            comwin_off(app);
        end

        % Value changed function: EditFieldFreq
        function EditFieldFreqValueChanged(app, event)
            value = app.EditFieldFreq.Value;
            app.FreqHzKnob.Value = value;
        end

        % Value changed function: EditFieldGain
        function EditFieldGainValueChanged(app, event)
            value = app.EditFieldGain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: EditFieldDur
        function EditFieldDurValueChanged(app, event)
            value = app.EditFieldDur.Value;
            app.DuratamsKnob.Value = value;
        end

        % Value changed function: FreqHzKnob
        function FreqHzKnobValueChanged(app, event)
            value = app.FreqHzKnob.Value;
            app.EditFieldFreq.Value = value;
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = app.GainKnob.Value;
            app.EditFieldGain.Value = value;
        end

        % Value changed function: DuratamsKnob
        function DuratamsKnobValueChanged(app, event)
            value = app.DuratamsKnob.Value;
            app.EditFieldDur.Value = value;
        end

        % Value changed function: CybergloveStreamONmissingButton
        function CybergloveStreamONmissingButtonValueChanged(app, event)
            % stream_on = app.CybergloveStreamONmissingButton.Value;
            % https://it.mathworks.com/help/matlab/creating_guis/wind-speed-gui-in-app-designer.html
            % Qui devo usare la funzione ReadData da cybercoso.m

        end

        % Button pushed function: Phase1TactorsStartButton
        function Phase1TactorsStartButtonPushed(app, event)
            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end
            
            try
                app.NextStimulationButton.Enable = "on";
                app.RepeatStimulationButton.Enable = "on";
                app.Phase1TactorsFinishButton.Enable = "on";

                post_it(app,["The stimulation-measurement sequence will start by pressing Deliver Next Stimulation";
                    "The number of stimulations is not fixed";
                    "Per ogni stimolazione Frequenza e Gain vengono scelti randomicamente,";
                    "mentre Durata e #Tactor vengono controllati dal pannello Tactors in basso.";
                    "Default sequenza bivariata, si può cambiare nel codice ENG NEEDED"]);

%                 post_it(app,["La sequenza di stimolazioni-misurazioni inizierà quando verrà premuto il tasto Invia Prossima Stimolazione";
%                     "Il numero di stimolazioni non è vincolato, e la sequenza continuerà fino alla pressione del pulsante Salva Fase 1 - Tactor";
%                     "Per ogni stimolazione Frequenza e Gain vengono scelti randomicamente,";
%                     "mentre Durata e #Tactor vengono controllati dal pannello Tactors in basso.";
%                     "Default sequenza bivariata, si può cambiare nel codice"]);


                freq = 10 * (70 + 5 * unidrnd(12));
                % Frequency is pulled from a discrete uniform distribution, from 70 to 130Hz, with 5Hz increments
                if app.bivariate
                    gain = 50 + 10 * unidrnd(20,20,1);
                    % Gain is pulled from a discrete uniform
                    % distribution, from 50 to 250, with increments
                    % of 10
                else
                    gain = 200;
                    % Gain is fixed, chosen as "strong but not too strong"
                end
                app.EditFieldFreq.Value = freq;
                app.EditFieldGain.Value = gain;
                pulse_delta = app.EditFieldDur.Value;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);

                t = strcat("Sent vibration with these characteristics:\n Freq = ", freq, "Hz, gain = ", gain, "\n");
                posti_it(app,t,true);

                app.Phase1TactorsStartButton.Enable = "off";
            catch
                post_it(app,"Tactors not connected properly, please retry")
            end
        end

        % Button pushed function: RepeatStimulationButton
        function RepeatStimulationButtonPushed(app, event)
            try
                % This time we get the vibration values straight from the knobs

                freq = 10 * app.EditFieldFreq.Value;
                gain = app.EditFieldGain.Value;
                pulse_delta = app.EditFieldDur.Value;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
            catch
                post_it(app, "Something went wrong, and it shouldn't have..")
            end
        end

        % Button pushed function: NextStimulationButton
        function NextStimulationButtonPushed(app, event)
            % We save the current answers and execute the next random vibration
            
            app.answers = [app.answers; app.EditFieldFreq.Value app.EditFieldGain.Value app.ListBox.Value app.ListBox_2.Value app.ListBox_3.Value app.ListBox_4.Value];

            freq = 10 * (60 + 5 * unidrnd(16));
            % Frequency is pulled from a discrete uniform distribution, from 60 to 140Hz, with 5Hz increments
            if app.bivariate
                gain = 50 + 10 * unidrnd(20,20,1);
                % Gain is pulled from a discrete uniform
                % distribution, from 50 to 250, with increments
                % of 10
            else
                gain = 200;
                % Gain is fixed, chosen as "strong but not too strong"
            end
            app.EditFieldFreq.Value = freq;         
            app.EditFieldGain.Value = gain;
            pulse_delta = app.EditFieldDur.Value;
            delay = pulse_delta / 10 - 1;
            vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

            vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);

            t = strcat("Sent vibration with these characteristics:\n Freq = ", freq, "Hz, gain = ", gain, "\n");
            posti_it(app,t,true);
        end

        % Button pushed function: Phase1TactorsFinishButton
        function Phase1TactorsFinishButtonPushed(app, event)
            if isfile(strcat(app.path_1, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"))
                post_it(app,"Answers file already present, please rename it to save it or delete it.")
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:6) = ["Freq" "Gain" "Answer 1" "Answer 2" "Answer 3" "Answer 4"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path_1, "\", "Answers_Phase1_Tactor_", app.today, ".xlsx"),"WriteMode","append");
                pause(1);
                app.answers = [];
                app.NextStimulationButton.Enable = "off";
                app.RepeatStimulationButton.Enable = "off";
                app.Phase1TactorsFinishButton.Enable = "off";
                app.Phase1TactorsStartButton.Enable = "on";
            end
        end

        % Button pushed function: Phase1GloveStartButton
        function Phase1GloveStartButtonPushed(app, event)
            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end
            
            try
                post_it(app,["La sequenza di stimolazioni-misurazioni inizierà quando verrà premuto il tasto Invia Prossima Stimolazione";
                    "Il numero di stimolazioni non è vincolato, e la sequenza continuerà fino alla pressione del pulsante Phase 1 Glove - Finish";
                    "Per ogni stimolazione Frequenza e Ampiezza vengono scelti randomicamente,";
                    "e vanno impostati manualmente sul secondo PC collegato ai MYKI Drivers";
                    "Default sequenza bivariata, si può cambiare nel codice ENG NEEDED"]);

                app.NextStimulationButton_2.Enable = "on";
                app.RepeatStimulationButton_2.Enable = "on";
                app.Phase1GloveFinishButton.Enable = "on";

                app.glove_freq = 50 + 5 * unidrnd(12);
                if app.bivariate
                    app.glove_ampl = 5 + 5 * unidrnd(7);
                else
                    app.glove_ampl = 25;
                    % Hard coded as middle ground amplitude, review in meetings
                end

                t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque \n");
                post_it(app, t, true);

                app.Phase1GlovesStartButton.Enable = "off";
            catch
                post_it(app,"Error message not written yet")
            end
        end

        % Button pushed function: NextStimulationButton_2
        function NextStimulationButton_2Pushed(app, event)
            % We save the current answers and execute the next random vibration
            
            app.answers = [app.answers; app.glove_freq app.glove_ampl app.ListBox.Value app.ListBox_2.Value app.ListBox_3.Value app.ListBox_4.Value];

            app.glove_freq = 50 + 5 * unidrnd(12);
            if app.bivariate
                app.glove_ampl = 5 + 5 * unidrnd(7);
            else
                app.glove_ampl = 25;
                % Hard coded as middle ground amplitude, review in meetings
            end

            t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque \n");
            post_it(app, t, true);

        end

        % Button pushed function: RepeatStimulationButton_2
        function RepeatStimulationButton_2Pushed(app, event)
            t = strcat("Setup Drivers to ", app.glove_freq, "Hz frequency and ", app.glove_ampl, "10^(-6)Nm torque again \n");
            post_it(app, t, true);
        end

        % Button pushed function: Phase1GloveFinishButton
        function Phase1GloveFinishButtonPushed(app, event)
            if isfile(strcat(app.path_1, "\", "Answers_Phase1_Glove_", app.today, ".xlsx"))
                post_it(app,"Answers file already present, please rename it to save it or delete it.")
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:6) = ["Freq" "Torque" "Answer 1" "Answer 2" "Answer 3" "Answer 4"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path_1, "\", "Answers_Phase1_Glove_", app.today, ".xlsx"),"WriteMode","append");
                pause(1);
                app.answers = [];
                app.NextStimulationButton_2.Enable = "off";
                app.RepeatStimulationButton_2.Enable = "off";
                app.Phase1GloveFinishButton.Enable = "off";
                app.Phase1GloveStartButton.Enable = "on";
            end
        end

        % Button pushed function: CreateTrialDirectoryButton
        function CreateTrialDirectoryButtonPushed(app, event)
            if ~isfolder(app.path_2)
                mkdir(app.path_2);
                addpath(app.path_2);
            end
            
            app.this_trial_path = strcat(app.path_2, "\Trial_", num2str(app.ThisTrialNEditField.Value), "_", app.TrialVariableDropDown.Value);
            if ~isfolder(app.this_trial_path)
                mkdir(app.this_trial_path);
                addpath(app.this_trial_path);
            else
                app.TextArea_2.Value = "Currently chosen trial number and variable directory already existing, all files will be overwritten!";
                pause(3);
            end
            
            dirs = dir;
            dirs = {dirs.name};
            dirs_ = dir(app.path_2);
            dirs_ = {dirs_.name};
            
            app.TextArea_2.Value = [dirs {'---'} {'And into today''s folder', char(strcat(app.path_2, ":"))} {'---'} dirs_ {'---'} {'--> If folders are present, the Sequence can be started using button on the right. <--'}];
        end

        % Button pushed function: StartSequenceButton
        function StartSequenceButtonPushed(app, event)
            % State Machine Implementation for this button
            % First state
            if isfolder(char(app.this_trial_path))
                if app.StartSequenceButton.Text == "Start Sequence"
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            axis_unit = " Hz";
                            app.MoreIntenseButton.Text = "More Intense";
                            app.LessIntenseButton.Text = "Less Intense";
                            app.SameIntensityButton.Text = "Same Intensity";
                        case "Ampl_Torque"
                            axis_unit = " exp(-6) Nm";
                            app.MoreIntenseButton.Text = "Illusion Sensed";
                            app.LessIntenseButton.Text = "No Illusion Sensed";
                            app.SameIntensityButton.Text = "Not Sure";
                    end
                    ylabel(app.UIAxes, strcat(app.TrialVariableDropDown.Value, axis_unit))
                    app.ThisTrialNEditField.Enable = "off";
                    app.TrialVariableDropDown.Enable = "off";
                    app.CreateTrialDirectoryButton.Enable = "off";
                    app.SaveDataButton.Enable = "off";
                    app.SaveImageButton.Enable = "off";
                    app.SavebothDataandImageButton.Enable = "off";
                    app.TextArea_2.Value = ["Sequence Starts when button Deliver First Stimulation is pressed";
                        "---";
                        strcat("You have chosen to run trial number ", num2str(app.ThisTrialNEditField.Value), " for the variable ", app.TrialVariableDropDown.Value);
                        "---";
                        "Make sure to check it hasn't been run before"];
                    app.StartSequenceButton.Text = "Deliver First Stimulation";

                    % Second State
                elseif app.StartSequenceButton.Text == "Deliver First Stimulation"
                    adaptive_sequence(app)

                    % Third State
                elseif app.StartSequenceButton.Text == "Deliver Second Stimulation"
                    adaptive_sequence(app)

                end
            else
                app.TextArea_2.Value = "Data Directory MUST be created before Start Sequence, use the button on the left and try again!";
            end
        end

        % Button pushed function: ShowDirectoriesButton
        function ShowDirectoriesButtonPushed(app, event)
            dirs = dir;
            dirs = {dirs.name};
            dirs_ = dir(app.path_2);
            dirs_ = {dirs_.name};
            
            app.TextArea_2.Value = [dirs {'---'} {'And into today''s folder', char(strcat(app.path_2, ":"))} dirs_ {'------------'} {'--> Choose the next trial number and the variable for which you will run the test. <--'}];
        end

        % Button pushed function: MoreIntenseButton
        function MoreIntenseButtonPushed(app, event)
            app.Z = 1;
            adaptive_sequence(app)
            app.same = 0;
        end

        % Button pushed function: LessIntenseButton
        function LessIntenseButtonPushed(app, event)
            app.Z = 0;
            adaptive_sequence(app)
            app.same = 0;
        end

        % Button pushed function: SameIntensityButton
        function SameIntensityButtonPushed(app, event)
            if app.same == 3
                app.Z = 0;
                adaptive_sequence(app);
                app.same = 0;
            else
                app.Z = 1;
                adaptive_sequence(app)
                app.same = app.same + 1;
            end
        end

        % Button down function: Phase2AdaptiveThresholdsTab
        function Phase2AdaptiveThresholdsTabButtonDown(app, event)
            % simple graph setup
            grid(app.UIAxes,'on');
            xticks(app.UIAxes, 0:1:length(app.var)+1);
            xlabel(app.UIAxes, "Stimulus Number")
            hold(app.UIAxes,'on');
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            save_phase2(app, true, false)
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            save_phase2(app, false, true)
        end

        % Button pushed function: SavebothDataandImageButton
        function SavebothDataandImageButtonPushed(app, event)
            save_phase2(app, true, true)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'F1MYKI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1TrainingandCalibrationTab = uitab(app.TabGroup);
            app.Phase1TrainingandCalibrationTab.Title = 'Phase 1 - Training and Calibration';
            app.GrigliaMAIN = uigridlayout(app.Phase1TrainingandCalibrationTab);
            app.InitializationPanel = uipanel(app.GrigliaMAIN);
            app.InitializationPanel.Title = 'Initialization';
            app.GridLayout3 = uigridlayout(app.InitializationPanel);
            app.UpdateCOMPortsButton = uibutton(app.GridLayout3, 'push');
            app.UpdateCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCOMPortsButtonPushed, true);
            app.UpdateCOMPortsButton.Text = {'Update'; 'COM Ports'};
            app.InitializeTactorsButton = uibutton(app.GridLayout3, 'push');
            app.InitializeTactorsButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeTactorsButtonPushed, true);
            app.InitializeTactorsButton.Text = {'Initialize'; 'Tactors'};
            app.ConnectCybergloveButton = uibutton(app.GridLayout3, 'push');
            app.ConnectCybergloveButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCybergloveButtonPushed, true);
            app.ConnectCybergloveButton.Text = {'Connect'; 'Cyberglove'};
            app.DisconnectCyberGloveButton = uibutton(app.GridLayout3, 'push');
            app.DisconnectCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectCyberGloveButtonPushed, true);
            app.DisconnectCyberGloveButton.Text = {'Disconnect'; 'CyberGlove'};
            app.CommandWindowPanel = uipanel(app.GridLayout3);
            app.CommandWindowPanel.Title = 'Command Window';
            app.GridLayout9 = uigridlayout(app.CommandWindowPanel);
            app.TextArea = uitextarea(app.GridLayout9);
            app.TextArea.Editable = 'off';
            app.COMPortsPanel = uipanel(app.GridLayout3);
            app.COMPortsPanel.Title = 'COM Ports';
            app.GridLayout8 = uigridlayout(app.COMPortsPanel);
            app.PortaListBox = uilistbox(app.GridLayout8);
            app.PortaListBox.Items = {};
            app.PortaListBox.Value = {};
            app.TactorsPanel = uipanel(app.GrigliaMAIN);
            app.TactorsPanel.Title = 'Tactors';
            app.GridLayout2 = uigridlayout(app.TactorsPanel);
            app.EditFieldFreq = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldFreq.Limits = [60 160];
            app.EditFieldFreq.ValueDisplayFormat = '%.0f';
            app.EditFieldFreq.ValueChangedFcn = createCallbackFcn(app, @EditFieldFreqValueChanged, true);
            app.EditFieldFreq.Value = 60;
            app.GainKnob = uiknob(app.GridLayout2, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.Value = 50;
            app.EditFieldGain = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldGain.Limits = [50 255];
            app.EditFieldGain.ValueDisplayFormat = '%.0f';
            app.EditFieldGain.ValueChangedFcn = createCallbackFcn(app, @EditFieldGainValueChanged, true);
            app.EditFieldGain.Value = 50;
            app.EditFieldDur = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldDur.Limits = [100 5000];
            app.EditFieldDur.ValueDisplayFormat = '%.0f';
            app.EditFieldDur.ValueChangedFcn = createCallbackFcn(app, @EditFieldDurValueChanged, true);
            app.EditFieldDur.Value = 100;
            app.DuratamsKnob = uiknob(app.GridLayout2, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.ValueChangedFcn = createCallbackFcn(app, @DuratamsKnobValueChanged, true);
            app.DuratamsKnob.Value = 100;
            app.FIREButton = uibutton(app.GridLayout2, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE';
            app.TactorsONPanel = uipanel(app.GridLayout2);
            app.TactorsONPanel.Title = 'Tactors ON';
            app.GridLayout6 = uigridlayout(app.TactorsONPanel);
            app.Tactor1CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor1CheckBox.Text = 'Tactor 1';
            app.Tactor2CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor2CheckBox.Text = 'Tactor 2';
            app.Tactor3CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor3CheckBox.Text = 'Tactor 3';
            app.FreqHzKnob = uiknob(app.GridLayout2, 'continuous');
            app.FreqHzKnob.Limits = [60 160];
            app.FreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqHzKnobValueChanged, true);
            app.FreqHzKnob.Value = 60;
            app.FreqHzLabel = uilabel(app.GridLayout2);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.GainLabel = uilabel(app.GridLayout2);
            app.GainLabel.Text = 'Gain';
            app.TimemsLabel = uilabel(app.GridLayout2);
            app.TimemsLabel.Text = 'Time (ms)';
            app.Questionario = uipanel(app.GrigliaMAIN);
            app.Questionario.Title = 'Phase 1 Stimulations and Questionnaires';
            app.GridLayout7 = uigridlayout(app.Questionario);
            app.ListBox = uilistbox(app.GridLayout7);
            app.ListBox.Items = {'0', '1'};
            app.ListBox.Enable = 'off';
            app.ListBox.Value = '0';
            app.ListBox_2 = uilistbox(app.GridLayout7);
            app.ListBox_2.Items = {'0', '1'};
            app.ListBox_2.Enable = 'off';
            app.ListBox_2.Value = '0';
            app.ListBox_3 = uilistbox(app.GridLayout7);
            app.ListBox_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_3.Enable = 'off';
            app.ListBox_3.Value = '5';
            app.ListBox_4 = uilistbox(app.GridLayout7);
            app.ListBox_4.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ListBox_4.Enable = 'off';
            app.ListBox_4.Value = '5';
            app.DidyouperceiveasensationofmovementY1N0Label = uilabel(app.GridLayout7);
            app.DidyouperceiveasensationofmovementY1N0Label.Text = 'Did you perceive a sensation of movement? - Y: 1, N: 0';
            app.Phase1TactorsFinishButton = uibutton(app.GridLayout7, 'push');
            app.Phase1TactorsFinishButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsFinishButtonPushed, true);
            app.Phase1TactorsFinishButton.Enable = 'off';
            app.Phase1TactorsFinishButton.Text = 'Phase 1 Tactors - Finish';
            app.Phase1GloveFinishButton = uibutton(app.GridLayout7, 'push');
            app.Phase1GloveFinishButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1GloveFinishButtonPushed, true);
            app.Phase1GloveFinishButton.Enable = 'off';
            app.Phase1GloveFinishButton.Text = 'Phase 1 Glove - Finish';
            app.Phase1TactorsStartButton = uibutton(app.GridLayout7, 'push');
            app.Phase1TactorsStartButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsStartButtonPushed, true);
            app.Phase1TactorsStartButton.Text = 'Phase 1 Tactors - Start';
            app.Phase1GloveStartButton = uibutton(app.GridLayout7, 'push');
            app.Phase1GloveStartButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1GloveStartButtonPushed, true);
            app.Phase1GloveStartButton.Text = 'Phase 1 Glove - Start';
            app.Defineitsstrengthitsvividnessfrom1to10Label = uilabel(app.GridLayout7);
            app.Defineitsstrengthitsvividnessfrom1to10Label.Text = 'Define its strength, its vividness, from 1 to 10.';
            app.Defineitsspeedfrom1to10Label = uilabel(app.GridLayout7);
            app.Defineitsspeedfrom1to10Label.Text = 'Define its speed, from 1 to 10.';
            app.DidyousenseatactilesensationAvibrationY1N0Label = uilabel(app.GridLayout7);
            app.DidyousenseatactilesensationAvibrationY1N0Label.Text = 'Did you sense a tactile sensation? A vibration? - Y: 1, N: 0';
            app.NextStimulationButton = uibutton(app.GridLayout7, 'push');
            app.NextStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NextStimulationButtonPushed, true);
            app.NextStimulationButton.Enable = 'off';
            app.NextStimulationButton.Text = 'Next Stimulation';
            app.RepeatStimulationButton = uibutton(app.GridLayout7, 'push');
            app.RepeatStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimulationButtonPushed, true);
            app.RepeatStimulationButton.Enable = 'off';
            app.RepeatStimulationButton.Text = 'Repeat Stimulation';
            app.NextStimulationButton_2 = uibutton(app.GridLayout7, 'push');
            app.NextStimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStimulationButton_2Pushed, true);
            app.NextStimulationButton_2.Enable = 'off';
            app.NextStimulationButton_2.Text = 'Next Stimulation';
            app.RepeatStimulationButton_2 = uibutton(app.GridLayout7, 'push');
            app.RepeatStimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimulationButton_2Pushed, true);
            app.RepeatStimulationButton_2.Enable = 'off';
            app.RepeatStimulationButton_2.Text = 'Repeat Stimulation';
            app.CyberGloveCalibrationDataPanel = uipanel(app.GrigliaMAIN);
            app.CyberGloveCalibrationDataPanel.Title = 'CyberGlove Calibration Data';
            app.GridLayout4 = uigridlayout(app.CyberGloveCalibrationDataPanel);
            app.PingCyberGloveButton = uibutton(app.GridLayout4, 'push');
            app.PingCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @PingCyberGloveButtonPushed, true);
            app.PingCyberGloveButton.Text = {'Ping'; 'CyberGlove'};
            app.SavexlsxPositionButton = uibutton(app.GridLayout4, 'push');
            app.SavexlsxPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavexlsxPositionButtonPushed, true);
            app.SavexlsxPositionButton.Text = {'Save .xlsx'; 'Position'};
            app.PositListBoxLabel = uilabel(app.GridLayout4);
            app.PositListBoxLabel.Text = 'Posit.';
            app.PositListBox = uilistbox(app.GridLayout4);
            app.PositListBox.Items = {'Flex', 'Ext'};
            app.PositListBox.Value = 'Flex';
            app.CybergloveStreamONmissingButton = uibutton(app.GridLayout4, 'state');
            app.CybergloveStreamONmissingButton.ValueChangedFcn = createCallbackFcn(app, @CybergloveStreamONmissingButtonValueChanged, true);
            app.CybergloveStreamONmissingButton.Text = 'Cyberglove Stream ON - missing';
            app.CyberGloveDataPanel = uipanel(app.GridLayout4);
            app.CyberGloveDataPanel.Title = 'CyberGlove Data';
            app.GridLayout10 = uigridlayout(app.CyberGloveDataPanel);
            app.UITable = uitable(app.GridLayout10);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Phase2AdaptiveThresholdsTab = uitab(app.TabGroup);
            app.Phase2AdaptiveThresholdsTab.Title = 'Phase 2 - Adaptive Thresholds';
            app.Phase2AdaptiveThresholdsTab.ButtonDownFcn = createCallbackFcn(app, @Phase2AdaptiveThresholdsTabButtonDown, true);
            app.GridLayout11 = uigridlayout(app.Phase2AdaptiveThresholdsTab);
            app.InitializeCommandWindowPanel = uipanel(app.GridLayout11);
            app.InitializeCommandWindowPanel.Title = 'Initialize - Command Window';
            app.GridLayout12 = uigridlayout(app.InitializeCommandWindowPanel);
            app.ThisTrialNEditFieldLabel = uilabel(app.GridLayout12);
            app.ThisTrialNEditFieldLabel.Text = 'This Trial N°';
            app.ThisTrialNEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ThisTrialNEditField.Value = 1;
            app.TrialVariableDropDownLabel = uilabel(app.GridLayout12);
            app.TrialVariableDropDownLabel.Text = 'Trial Variable';
            app.TrialVariableDropDown = uidropdown(app.GridLayout12);
            app.TrialVariableDropDown.Items = {'Frequency', 'Ampl_Torque'};
            app.TrialVariableDropDown.Value = 'Frequency';
            app.TextArea_2 = uitextarea(app.GridLayout12);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Placeholder = 'Choose progressive number and variable for this trial showing the directories already created, with Show Directories';
            app.CreateTrialDirectoryButton = uibutton(app.GridLayout12, 'push');
            app.CreateTrialDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTrialDirectoryButtonPushed, true);
            app.CreateTrialDirectoryButton.Text = 'Create Trial Directory';
            app.StartSequenceButton = uibutton(app.GridLayout12, 'push');
            app.StartSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @StartSequenceButtonPushed, true);
            app.StartSequenceButton.Text = 'Start Sequence';
            app.LessIntenseButton = uibutton(app.GridLayout12, 'push');
            app.LessIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @LessIntenseButtonPushed, true);
            app.LessIntenseButton.Enable = 'off';
            app.LessIntenseButton.Text = 'Less Intense';
            app.SameIntensityButton = uibutton(app.GridLayout12, 'push');
            app.SameIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @SameIntensityButtonPushed, true);
            app.SameIntensityButton.Enable = 'off';
            app.SameIntensityButton.Text = 'Same Intensity';
            app.ShowDirectoriesButton = uibutton(app.GridLayout12, 'push');
            app.ShowDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPushed, true);
            app.ShowDirectoriesButton.Text = 'Show Directories';
            app.MoreIntenseButton = uibutton(app.GridLayout12, 'push');
            app.MoreIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @MoreIntenseButtonPushed, true);
            app.MoreIntenseButton.Enable = 'off';
            app.MoreIntenseButton.Text = 'More Intense';
            app.OutputGraphsPanel = uipanel(app.GridLayout11);
            app.OutputGraphsPanel.Title = 'Output Graphs';
            app.GridLayout14 = uigridlayout(app.OutputGraphsPanel);
            app.UIAxes = uiaxes(app.GridLayout14);
            title(app.UIAxes, 'Adaptive Sequence')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveDataPanel = uipanel(app.GridLayout11);
            app.SaveDataPanel.Title = 'Save Data';
            app.GridLayout15 = uigridlayout(app.SaveDataPanel);
            app.SaveDataButton = uibutton(app.GridLayout15, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.SaveImageButton = uibutton(app.GridLayout15, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SavebothDataandImageButton = uibutton(app.GridLayout15, 'push');
            app.SavebothDataandImageButton.ButtonPushedFcn = createCallbackFcn(app, @SavebothDataandImageButtonPushed, true);
            app.SavebothDataandImageButton.Enable = 'off';
            app.SavebothDataandImageButton.Text = 'Save both Data and Image';
            app.Phase3Tab = uitab(app.TabGroup);
            app.Phase3Tab.Title = 'Phase 3';
            app.Phase4Tab = uitab(app.TabGroup);
            app.Phase4Tab.Title = 'Phase 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end