classdef MYKI_PsychoPhysics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Phase1TrainingandCalibrationTab  matlab.ui.container.Tab
        GrigliaMAIN                     matlab.ui.container.GridLayout
        InfoBoxInitializationPanel      matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        SurveyIdentifierEditField       matlab.ui.control.EditField
        SurveyIdentifierEditField_3Label  matlab.ui.control.Label
        EditSubjectsJointsListButton    matlab.ui.control.Button
        ShowDirectoriesButtonPhase1     matlab.ui.control.Button
        CloseTactorInterfaceButton      matlab.ui.control.Button
        InitializeTactorInterfaceButton  matlab.ui.control.Button
        COMPortsUpdatePanel             matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PortaListBox                    matlab.ui.control.ListBox
        InfoBox1                        matlab.ui.control.TextArea
        VibrationControlsPanel          matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TactorsONPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Tactor3CheckBox                 matlab.ui.control.CheckBox
        Tactor2CheckBox                 matlab.ui.control.CheckBox
        Tactor1CheckBox                 matlab.ui.control.CheckBox
        FIREButton                      matlab.ui.control.Button
        SurveyedVariablesPh12Panel      matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        CurrentsCheckBox                matlab.ui.control.CheckBox
        GainTorqueCheckBox              matlab.ui.control.CheckBox
        FrequencyCheckBox               matlab.ui.control.CheckBox
        TimeVariantCheckBox             matlab.ui.control.CheckBox
        FreqHzKnob                      matlab.ui.control.Knob
        TimemsLabel                     matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        FreqHzLabel                     matlab.ui.control.Label
        DuratamsKnob                    matlab.ui.control.Knob
        EditFieldDur                    matlab.ui.control.NumericEditField
        EditFieldGain                   matlab.ui.control.NumericEditField
        GainKnob                        matlab.ui.control.Knob
        EditFieldFreq                   matlab.ui.control.NumericEditField
        ProprioceptiveIllusionSurveyPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        OverwriteLastAnswersButton_2    matlab.ui.control.Button
        SurveyQuestionsPanel            matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        DropDown3                       matlab.ui.control.DropDown
        DropDown7                       matlab.ui.control.DropDown
        DropDown6                       matlab.ui.control.DropDown
        DropDown5                       matlab.ui.control.DropDown
        DropDown4                       matlab.ui.control.DropDown
        DropDown2                       matlab.ui.control.DropDown
        DropDown1                       matlab.ui.control.DropDown
        DefinethespeedofthissensedmovementLabel  matlab.ui.control.Label
        DefinethedistancethismovementspannedontoLabel  matlab.ui.control.Label
        DefinethestrengthvividnessofthemovementsensationLabel  matlab.ui.control.Label
        WhatjointdidyoufeelmoveLabel    matlab.ui.control.Label
        InwhichdirectionwasthatjoingmovingLabel  matlab.ui.control.Label
        DidyoufeelasensationofmovementLabel  matlab.ui.control.Label
        DidyoufeelatactilevibrationLabel  matlab.ui.control.Label
        RepeatStimulationButton         matlab.ui.control.Button
        SaveNextStimulationButton_2     matlab.ui.control.Button
        SaveSurveyAnswersButton         matlab.ui.control.Button
        StartSurveyButton               matlab.ui.control.Button
        Phase2GloveTrackingTab          matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        CyberGloveCalibrationDataPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CyberGloveDataPanel             matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        CyberGloveStreamOFFButton       matlab.ui.control.StateButton
        PositListBox                    matlab.ui.control.ListBox
        PositListBoxLabel               matlab.ui.control.Label
        SavexlsxPositionButton          matlab.ui.control.Button
        PingCyberGloveButton            matlab.ui.control.Button
        ProprioceptiveIllusionSurveyPanel_2  matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        OverwriteLastAnswersButton      matlab.ui.control.Button
        SurveyQuestionsPanel_2          matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        DropDown3_2                     matlab.ui.control.DropDown
        DropDown5_2                     matlab.ui.control.DropDown
        DropDown4_2                     matlab.ui.control.DropDown
        DropDown2_2                     matlab.ui.control.DropDown
        DropDown1_2                     matlab.ui.control.DropDown
        DefinethestrengthvividnessofthemovementsensationLabel_2  matlab.ui.control.Label
        WhatjointdidyoufeelmoveLabel_2  matlab.ui.control.Label
        InwhichdirectionwasthatjoingmovingLabel_2  matlab.ui.control.Label
        DidyoufeelasensationofmovementLabel_2  matlab.ui.control.Label
        DidyoufeelatactilevibrationLabel_2  matlab.ui.control.Label
        RepeatStimulationButton_2       matlab.ui.control.Button
        SaveNextStimulationButton       matlab.ui.control.Button
        SaveSurveyAnswersButton_2       matlab.ui.control.Button
        StartSurveyButton_2             matlab.ui.control.Button
        InfoBoxInitializationPanel_2    matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        SurveyIdentifierEditField_2     matlab.ui.control.EditField
        SurveyIdentifierEditFieldLabel  matlab.ui.control.Label
        EditSubjectsJointsListButton_2  matlab.ui.control.Button
        ShowDirectoriesButtonPhase2     matlab.ui.control.Button
        CloseGloveInterfaceButton       matlab.ui.control.Button
        InitializeGloveInterfaceButton  matlab.ui.control.Button
        COMPortsUpdatePanel_2           matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        PortaListBox_2                  matlab.ui.control.ListBox
        InfoBox2                        matlab.ui.control.TextArea
        Phase3AdaptiveThresholdsTab     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SaveDataPanel                   matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        AbortSequenceButton             matlab.ui.control.Button
        SavebothDataandImageButton      matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        OutputGraphsPanel               matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        SequenceInitializationandExecutionPanel  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        EditSubjectsJointsListButton_3  matlab.ui.control.Button
        TrialIdentifierEditField        matlab.ui.control.EditField
        TrialIdentifierEditFieldLabel   matlab.ui.control.Label
        DeleteLastStepButton            matlab.ui.control.Button
        MoreIntenseButton               matlab.ui.control.Button
        ShowDirectoriesButton           matlab.ui.control.Button
        SameIntensityButton             matlab.ui.control.Button
        LessIntenseButton               matlab.ui.control.Button
        StartSequenceButton             matlab.ui.control.Button
        CreateTrialDirectoryButton      matlab.ui.control.Button
        InfoBox3                        matlab.ui.control.TextArea
        TrialVariableDropDown           matlab.ui.control.DropDown
        TrialVariableDropDownLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        today;
        subj;
        path_1;
        path_2;
        path_3;
        dowefreq;
        doweampl;
        
        % Phase 1 Properties
        ti;
        com_port;
        time_variant;           % if true we vary randomly also the time pulse duration
        answers double;         % ["Freq" "Gain" "Duration" "Vibration" "Illusion" "Joint" "Direction" "Strength" "ROM" "Speed"]
        joints = {'None'};

        % Phase 2 properties
        this_trial_path_2;
        DataTags;
        glove;
        glove_cal_pulls;
        glove_freq;
        glove_ampl;
        time;
        currents;

        % Phase 3 properties
        n_trial;
        this_trial_path_3;
        c;
        m;
        Z;
        Z_old;
        phi = 0.75;
        var;
        same = 0;
        % this is the control variable that counts how many answers are
        % "the same sensation" in the sequence. when it's =3 things happen
        f_interval = 5;
        % this is the interval in which frequency must stay to enable
        % the sequence termination
        a_interval = 3;
        % this is the interval in which torque must stay to enable
        % the sequence termination
        t_interval = 50;
        % this is the interval in which impulse duration must stay to enable
        % the sequence termination
        c_interval = 100;
        % this is the interval in which current must stay to enable
        % the sequence termination
        s_cutoff = 6;
        % this is the number of stimulations in which the VAR must stay to
        % enable the sequence termination
        aborted = 0;
    end

    methods (Access = private)

        function vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index)
            devIndex = 0;               % Hard coded until pilot trials
            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
        end

        function adaptive_sequence3(app)
            switch length(app.var)
                case 0
                    if app.TrialVariableDropDown.Value == "Frequency"
                        app.c = 160;
                        % empirically chosen constant
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.var = -30;
                        % starting frequency value is CAMBIARE,
                        % empirically chosen
                        app.same = 0;
                        % for redundance
                        app.var = app.var + app.c / (1 + app.m) * (app.Z - app.phi);
                        app.var = round(app.var);
                        % formula for ascending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Frequency of ", num2str(app.var(end)), " and fixed Torque."); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons."];
                    elseif app.TrialVariableDropDown.Value == "Torque"
                        app.c = 30;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.var = 49;
                        % starting torque value is 40, maximal torque
                        % deliverable (50 - 50*0.2)
                        app.same = 0;
                        % for redundance
                        app.var = app.var - app.c / (1 + app.m) * (app.Z - app.phi);
                        app.var = round(app.var);
                        % formula for descending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Torque of ", num2str(app.var(end)), " and fixed Frequency."); "---";
                            "Please, keep in mind sensed kinesthetic illusion for future comparisons."];
                    elseif app.TrialVariableDropDown.Value == "Impulse_Duration"
                        app.c = 1000;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.var = 1250;
                        % starting time value is 1000ms
                        app.same = 0;
                        % for redundance
                        app.var = app.var - app.c / (1 + app.m) * (app.Z - app.phi);
                        app.var = round(app.var);
                        % formula for descending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Time Duration of ", num2str(app.var(end)), " and fixed Frequency and Amplitude."); "---";
                            "Please, keep in mind sensed vibration for future comparisons."];
                    elseif app.TrialVariableDropDown.Value == "Current_Upper"
                        app.c = 1000;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.var = 0;
                        % starting current value is 300 mA, upward sequence
                        app.same = 0;
                        % for redundance
                        app.var = app.var + app.c / (1 + app.m) * (app.Z - app.phi);
                        app.var = round(app.var);
                        % formula for descending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Current of ", num2str(app.var(end)), " and fixed Frequency."); "---";
                            "Please, keep in mind sensed vibration for future comparisons."];
                    elseif app.TrialVariableDropDown.Value == "Current_Lower"
                        app.c = 1000;
                        % empirically chosen constant TO BE DETERMINED
                        app.m = 0;
                        % number of flexes in curve, initialize 0
                        app.Z = 1;
                        % positive or negative answer, 1 is positive
                        app.Z_old = 1;
                        % storage for old answer
                        app.var = 2300;
                        % starting current value is 2000 mA, downward
                        % sequence
                        app.same = 0;
                        % for redundance
                        app.var = app.var - app.c / (1 + app.m) * (app.Z - app.phi);
                        app.var = round(app.var);
                        % formula for descending threshold
                        app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with Current of ", num2str(app.var(end)), " and fixed Frequency."); "---";
                            "Please, keep in mind sensed vibration for future comparisons."];
                    end
                    app.StartSequenceButton.Text = "Deliver Second Stimulation";
                    app.MoreIntenseButton.Enable = "off";
                    app.LessIntenseButton.Enable = "off";
                    app.SameIntensityButton.Enable = "off";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                    app.DeleteLastStepButton.Enable = 0;
                case 1
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Torque"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Impulse_Duration"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Current_Upper"
                            new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                        case "Current_Lower"
                            new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                    end
                    new_var = round(new_var);
                    app.var = [app.var new_var];
                    app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable fixed."); "---";
                        "Please express a comparison between this perception and the last one.";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer."];
                    app.StartSequenceButton.Text = "Sequence Ongoing";
                    app.StartSequenceButton.Enable = "off";
                    app.MoreIntenseButton.Enable = "on";
                    app.LessIntenseButton.Enable = "on";
                    app.SameIntensityButton.Enable = "on";
                    app.MoreIntenseButton.Visible = "on";
                    app.LessIntenseButton.Visible = "on";
                    app.SameIntensityButton.Visible = "on";
                    app.DeleteLastStepButton.Enable = 1;
                otherwise
                    t_in = "---";
                    if ~app.aborted
                        if app.Z ~= app.Z_old
                            app.m = app.m + 1;
                            if (app.m >= 6 && abs(app.var(end-1)-app.var(end-2))<=1)
                                app.m = 6;
                            end
                        end

                        switch app.TrialVariableDropDown.Value
                            case "Frequency"
                                new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                                if new_var >= 180
                                    new_var = 180;
                                    t_in = ["---"; "Frequency value DEFAULTED to 110Hz for machine constraints."; "---"];
                                end
                                if new_var <= 1
                                    new_var = 1;
                                    t_in = ["---"; "Frequency value DEFAULTED to 30Hz for machine constraints."; "---"];
                                end
                            case "Torque"
                                new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                                if new_var >= 40
                                    new_var = 40;
                                    t_in = ["---"; "Torque value DEFAULTED to 40 * 10^(-6)Nm for machine constraints."; "---"];
                                end
                                if new_var <= 1
                                    new_var = 1;
                                    t_in = ["---"; "Torque value DEFAULTED to 1 * 10^(-6)Nm for machine constraints."; "---"];
                                end
                            case "Impulse_Duration"
                                new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                                if new_var >= 1000
                                    new_var = 1000;
                                    t_in = ["---"; "Duration value DEFAULTED to 1000ms."; "---"];
                                end
                                if new_var <= 1
                                    new_var = 1;
                                    t_in = ["---"; "Duration value DEFAULTED to 10ms for negative time calculated."; "---"];
                                end
                            case "Current_Upper"
                                new_var = app.var(end) + app.c / (1 + app.m) * (app.Z - app.phi);
                                if new_var >= 2000
                                    new_var = 2000;
                                    t_in = ["---"; "Duration value DEFAULTED to 2000mA."; "---"];
                                end
                                if new_var <= 1
                                    new_var = 1;
                                    t_in = ["---"; "Duration value DEFAULTED to 1mA for machine constraints."; "---"];
                                end
                            case "Current_Lower"
                                new_var = app.var(end) - app.c / (1 + app.m) * (app.Z - app.phi);
                                if new_var >= 2000
                                    new_var = 2000;
                                    t_in = ["---"; "Duration value DEFAULTED to 2000mA."; "---"];
                                end
                                if new_var <= 1
                                    new_var = 1;
                                    t_in = ["---"; "Duration value DEFAULTED to 1mA for machine constraints."; "---"];
                                end
                        end
                        new_var = round(new_var);
                    else
                        new_var = 0;
                    end
                    app.var = [app.var new_var];
                    app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable(s) fixed."); t_in;
                        "Please express a comparison between this perception and the last one.";
                        "---";
                        "Was it more intense? Less intense? Or the same intensity?";
                        "---";
                        "Use the buttons on the right to answer.";
                        "---";
                        "Now the sequence will keep going with each comparison answered!"];
                    if ~app.aborted
                        if length(app.var) > 20
                            switch app.TrialVariableDropDown.Value
                                case "Frequency"
                                    interval = app.f_interval;     % These intervals are chosen as intervals of non intellegible difference
                                case "Torque"
                                    interval = app.a_interval;
                                case "Impulse_Duration"
                                    interval = app.t_interval;
                                case "Current_Upper"
                                    interval = app.c_interval;
                                case "Current_Lower"
                                    interval = app.c_interval;
                            end
                            if max(app.var(end-app.s_cutoff:end)) - min(app.var(end-app.s_cutoff:end)) <= interval
                                app.InfoBox3.Value = [strcat("Deliver MYKI stimulation with ", app.TrialVariableDropDown.Value, " of ", num2str(app.var(end)), " and the other variable(s) fixed."); t_in;
                                    "Please express a comparison between this perception and the last one.";
                                    "---";
                                    "Was it more intense? Less intense? Or the same intensity?";
                                    "---";
                                    "Use the buttons on the right to answer.";
                                    "---";
                                    "Now the sequence will keep going with each comparison answered!";
                                    "---";
                                    strcat("The last ", num2str(app.s_cutoff), " stimulations meet the termination criteria, terminate at will.")];
                                scroll(app.InfoBox3, "bottom")
                                app.SaveDataButton.Enable = "on";
                                app.SaveImageButton.Enable = "on";
                                app.SavebothDataandImageButton.Enable = "on";
                            end
                        end
                    else
                        app.InfoBox3.Value = ["Sequence Aborted, by padding 10 zeros into the data.";
                            "---";
                            strcat("Exit this sequence by saving.")];
                        scroll(app.InfoBox3, "bottom")
                        app.SaveDataButton.Enable = "on";
                        app.SaveImageButton.Enable = "on";
                        app.SavebothDataandImageButton.Enable = "on";
                        app.AbortSequenceButton.Enable = 0;
                    end
                    app.Z_old = app.Z;
            end
            draw_sequence3(app)
        end

        function draw_sequence3(app)
            if app.TrialVariableDropDown.Value == "Frequency"
                title(app.UIAxes, 'Frequency Adaptive Sequence');
                app.UIAxes.YLim = [0 max(app.var)+10];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Torque"
                title(app.UIAxes, 'Current Ampl / Torque Adaptive Sequence');
                app.UIAxes.YLim = [min(app.var)-5 45];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Impulse_Duration"
                title(app.UIAxes, 'Impulse Time Duration / DESC Adaptive Sequence');
                app.UIAxes.YLim = [0 max(app.var)+100];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Current_Upper"
                title(app.UIAxes, 'Driving Current / Upper Limit (Pain or Best)');
                app.UIAxes.YLim = [0 max(app.var)+100];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            elseif app.TrialVariableDropDown.Value == "Current_Lower"
                title(app.UIAxes, 'Driving Current / Lower Limit (Perception Threshold)');
                app.UIAxes.YLim = [min(app.var)-100 2100];
                xticks(app.UIAxes, 0:1:length(app.var)+1);
            end
            app.UIAxes.XLim = [0 length(app.var)+1];
            plot(app.UIAxes,app.var,"r","Marker","o","MarkerSize",8)
        end

        function save_phase3(app, data, image)
            if data == true
                % Save only the data gathered
                writematrix(app.var, strcat(app.this_trial_path_3, "\Phase3_Trial_", app.TrialIdentifierEditField.Value, "_", app.TrialVariableDropDown.Value, "_Data.xlsx"));
            end
            if image == true
                % Save the image in png format
                figure;
                % Create new axis
                newAxes = axes;
                % Copy all objects from UIAxes to new axis
                newAxes.Title= app.UIAxes.Title;
                newAxes.XLabel = app.UIAxes.XLabel;
                newAxes.YLabel = app.UIAxes.YLabel;
                grid(newAxes,'on');
                copyobj(app.UIAxes.Children, newAxes)
                % in order to keep titles and labels on uiaxes
                xlabel(app.UIAxes, newAxes.XLabel.String);
                ylabel(app.UIAxes, newAxes.YLabel.String);
                title(app.UIAxes, newAxes.Title.String);
                fig2Save = ancestor(newAxes, 'figure');
                fig2Save.Visible='off';
                saveas(fig2Save, strcat(app.this_trial_path_3, "\Phase3_Trial_", app.TrialIdentifierEditField.Value, "_", app.TrialVariableDropDown.Value, "_Image.png"));
            end
            app.InfoBox3.Value = "File(s) saved. Now more data can be gathered in 3... 2...";
            pause(3);
            % resetting state as new
            app.StartSequenceButton.Enable = "on";
            app.StartSequenceButton.Text = "Start Sequence";
            app.MoreIntenseButton.Enable = 0;
            app.LessIntenseButton.Enable = 0;
            app.SameIntensityButton.Enable = 0;
            app.DeleteLastStepButton.Enable = 0;
            app.TrialIdentifierEditField.Enable = 1;
            app.TrialVariableDropDown.Enable = 1;
            app.CreateTrialDirectoryButton.Enable = 1;
            app.SaveDataButton.Enable = 0;
            app.SaveImageButton.Enable = 0;
            app.SavebothDataandImageButton.Enable = 0;
            app.AbortSequenceButton.Enable = 0;
            app.var = [];

            app.UIAxes.NextPlot = 'ReplaceChildren';
            hold(app.UIAxes, "off");

            app.InfoBox3.Value = [app.InfoBox3.Value; "---"; "Now."];
        end
 
        function KillTactors(app) %#ok<MANU> 
            try
                clear app.ti
            catch
                disp("No Tactor Interfaces connected at the moment");
            end
        end

        function KillGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("No CyberGloves connected at the moment");
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InfoBox1.Value = "Opening prompt for trial directory selection in 3...";
            pause(3);
            d = uigetdir;
            if d == 0
                app.InfoBox1.Value = "No folder selected, closing app.";
                err_msg = msgbox("No folder selected, application will close","Error","error");
                uiwait(err_msg);
                pause(1);
                delete(app);
                return
            else
                cd(d);
            end
            
            dirs = dir;
            dirs = string({dirs.name})';
            app.InfoBox1.Value = [dirs; "---"; "Directories list"];
            scroll(app.InfoBox1,"bottom");

            if isfolder("MATLAB64_TDK-1.0.5.0\")
                addpath MATLAB64_TDK-1.0.5.0\
            else
                app.InfoBox1.Value = "Tactors' mandatory library MATLAB64_TDK-1.0.5.0 not found, closing app.";
                err_msg = msgbox("Tactors Matlab TDK not found in chosen directory, please select a trial directory containing the MATLAB64_TDK-1.0.5.0\ directory","Error","error");
                uiwait(err_msg);
                pause(1);
                delete(app);
                return
            end

            if ~isfile("Cyberglove.m")
                err_msg = msgbox("Cyberglove.m file dependancy not found in main directory, please add or Phase 2 will not work","Error","warn");
                uiwait(err_msg);
            end
            
            prompt = 'Enter Subject Progressive Number:';
            dlgtitle = 'Subj ID';
            dims = [1 35];
            definput = {'01'};
            app.subj = inputdlg(prompt,dlgtitle,dims,definput);

            if isempty(app.subj)
                app.subj = {'69'};
                app.InfoBox1.Value = "Defaulted to Subject Number Sixtynice";
                pause(1.69);
            end                
                        
            app.today = string(datetime('now','Format','dd-MM-yy'));
            app.path_1 = strcat("Subj_", app.subj,"\Phase1_",app.today);
            app.path_2 = strcat("Subj_", app.subj,"\Phase2_",app.today);
            app.path_3 = strcat("Subj_", app.subj,"\Phase3_",app.today);

            if ~exist(app.path_1, 'dir')
                mkdir(app.path_1);
                addpath(app.path_1);
            end

            if ~exist(app.path_2,"dir")
                mkdir(app.path_2);
                addpath(app.path_2);
            end

            if ~exist(app.path_3,"dir")
                mkdir(app.path_3);
                addpath(app.path_3);
            end

            taggies =  load("DataTags.mat", "DataTags");
            taggies = taggies.DataTags;
            taggies = taggies(2:end-1);   % remove first control char and last zero
            app.DataTags = cellstr(taggies);
            
            % Compile list of COM ports available at startup
            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.com_port = app.PortaListBox.Value;
            
            if exist(strcat("Subj_", app.subj, "\Jointslist.txt"),"file")
                app.joints = readcell(strcat("Subj_", app.subj,"\JointsList.txt"));
            end
            
            app.DropDown3.Items = app.joints;
            app.DropDown3_2.Items = app.joints;
    
            app.EditFieldDur.Value = 1000;
            app.EditFieldFreq.Value = 100;
            app.EditFieldGain.Value = 200;

            app.DuratamsKnob.Value = app.EditFieldDur.Value;
            app.FreqHzKnob.Value = app.EditFieldFreq.Value;
            app.GainKnob.Value = app.EditFieldGain.Value;

            pause(2);

            app.InfoBox1.Value = ["In this Phase 1 of the MYKI Proprioception Trial we will deliver vibrations through skin vibrators, called Tactors.";
                "---";
                "Please start by connecting the Tactor Unit to the PC.";
                "Then select the device in the COM Ports list (after Updating the list by clicking on the tab title) and press Initialize Tactor Interface."];
            app.InfoBox2.Value = ["In this Phase 2 of the MYKI Proprioception Trial we will deliver vibrations through MYKI implanted magnets, through the MYKI Drivers.";
                "---";
                "Please start by connecting the CyberGlove to the PC.";
                "Then select the device in the COM Ports list (after Updating the list by clicking on the tab title) and press Initialize Glove Interface."];
            app.InfoBox3.Value = ["In this Phase 3 of the MYKI Proprioception Trial we will deliver vibrations through MYKI implanted magnets, in an adaptive sequence.";
                "---";
                "The sequence values will be shown here, and then used to set up the Drivers on the Driving PC.";
                "This page is used only for guidance and to save the characteristics of the delivered vibrations.";
                "---";
                "Remember to create new Trial Directory or files will be overwritten."];

        end

        % Button pushed function: InitializeTactorInterfaceButton
        function InitializeTactorInterfaceButtonPushed(app, event)
            try
                app.ti = TactorInterfaceML;
                app.ti.Discover;
                app.ti.Connect(app.PortaListBox.Value, 1);
                if app.ti.deviceConnected == true
                    pause(2);
                    text = ["Connection to Tactor Interface successful. You can freely use the Tactors now from the panel below.";
                        "---";
                        "Before starting a Survey choose the Survey Number by showing the ones already used."];
                    app.FIREButton.Enable = 1;
                    app.InitializeTactorInterfaceButton.Enable = 0;
                    app.ProprioceptiveIllusionSurveyPanel.Enable = 1;
                else
                    text = "An error occurred during the connection, try selecting a different port.";
                end
            catch
                text = "An error occurred, Tactor Interface already present. Close it before retrying.";
            end
            
            app.CloseTactorInterfaceButton.Enable = 1;
            app.InfoBox1.Value = text;
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            try
                freq = round(10 * app.EditFieldFreq.Value);                        % ChangeFreq wants frequency 1/10 Hz
                gain = round(app.EditFieldGain.Value);
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
                pulse_delta = round(app.EditFieldDur.Value) / 2;
                % pulse_delta, its duration, is divided by two because the
                % max the function can take is 2500 ms.
                delay = pulse_delta / 10 - 1;
                % by applying a delay (divided by 10 because the app wants
                % centiseconds) we can double stimulation length.

                vibrateTactors(app,freq,gain,pulse_delta,delay,vib_index)
                text = ["---";
                    strcat("Delivered a vibration with Frequency ", num2str(freq/10), " Hz, and Gain ", num2str(gain), ".");
                    "---";
                    "If no vibration was detected try to select different Tactors in the check boxes, or manually move the tactor jacks.";
                    "---";
                    "When the subject has recognized il kinaesthetic illusion use the Questionnaire below to record data."];
            catch
                text = "An error occurred, likely the connection to Tactors is lost. Press the button Close Tactors Interface and reconnect.";
            end

            app.InfoBox1.Value = [app.InfoBox1.Value; text];
            scroll(app.InfoBox1, 'bottom');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all interfaces before closing
            KillGlove(app)
            KillTactors(app)
            
            % Delete empty folders
            try
            if length({dir(app.path_1).name}) <= 2
                rmpath(strcat(app.path_1,"\"))
                rmdir(strcat(app.path_1,"\"))                
            end

            if length({dir(app.path_2).name}) <= 2
                rmpath(strcat(app.path_2,"\"))
                rmdir(strcat(app.path_2,"\"))
            end

            if length({dir(app.path_3).name}) <= 2
                rmpath(strcat(app.path_3,"\"))
                rmdir(strcat(app.path_3,"\"))
            end
            catch
                disp("lol");
            end

            delete(app)            
        end

        % Value changed function: EditFieldFreq
        function EditFieldFreqValueChanged(app, event)
            value = app.EditFieldFreq.Value;
            app.FreqHzKnob.Value = value;
        end

        % Value changed function: EditFieldGain
        function EditFieldGainValueChanged(app, event)
            value = app.EditFieldGain.Value;
            app.GainKnob.Value = value;
        end

        % Value changed function: EditFieldDur
        function EditFieldDurValueChanged(app, event)
            value = app.EditFieldDur.Value;
            app.DuratamsKnob.Value = value;
        end

        % Value changed function: FreqHzKnob
        function FreqHzKnobValueChanged(app, event)
            value = round(app.FreqHzKnob.Value);
            app.EditFieldFreq.Value = value;
        end

        % Value changed function: GainKnob
        function GainKnobValueChanged(app, event)
            value = round(app.GainKnob.Value);
            app.EditFieldGain.Value = value;
        end

        % Value changed function: DuratamsKnob
        function DuratamsKnobValueChanged(app, event)
            value = round(app.DuratamsKnob.Value);
            app.EditFieldDur.Value = value;
        end

        % Button pushed function: StartSurveyButton
        function Phase1TactorsStartButtonPushed(app, event)
            try
                if app.StartSurveyButton.Text == "Start Survey"
                    
                    app.InfoBox1.Value = ["The stimulation-survey will start by pressing First Stimulation";
                        "---";
                        "The number of stimulations is not fixed.";
                        "---";
                        "For every stimulation frequency and gain are computed randomly.";
                        "---";
                        "To change duration and tactors used knobs below are still available."
                        ];
                    app.StartSurveyButton.Text = "First Stimulation";
                    app.dowefreq = app.FrequencyCheckBox.Value;
                    app.doweampl = app.GainTorqueCheckBox.Value;
                    app.time_variant = app.TimeVariantCheckBox.Value;
                    app.SurveyedVariablesPh12Panel.Enable = 0;

                elseif app.StartSurveyButton.Text == "First Stimulation"
                    app.SaveNextStimulationButton_2.Enable = "on";
                    app.RepeatStimulationButton.Enable = "on";
                    app.SaveSurveyAnswersButton.Enable = "on";
                    app.FreqHzKnob.Enable = 0;
                    app.GainKnob.Enable = 0;
                    app.SurveyQuestionsPanel.Enable = 1;
                    app.OverwriteLastAnswersButton_2.Enable = 1;

                    if app.dowefreq
                        freq = 10 * (70 + 5 * unidrnd(12));
                    else
                        freq = 900;        % 90Hz
                    end
                    % Frequency is pulled from a discrete uniform distribution, from 70 to 130Hz, with 5Hz increments
                    if app.doweampl
                        gain = 50 + 10 * unidrnd(20);
                        % Gain is pulled from a discrete uniform
                        % distribution, from 50 to 250, with increments
                        % of 10
                    else
                        gain = app.EditFieldGain.Value;
                        % Gain is fixed, chosen as "strong but not too strong"
                    end

                    if app.time_variant
                        pulse_delta = ( 500 * unidrnd(6) )/2;
                        % Gain is pulled from a discrete uniform
                        % distribution, from 500 to 3000 ms, with increments
                        % of 500; it is divided by 2 because the delay
                        % doubles it again later (you can't deliver more
                        % than 2500 ms of vibration with no delay-doubling)
                    else
                        pulse_delta = app.EditFieldDur.Value / 2;
                        % Gain is fixed, pulled from the Time dial value
                    end
                    
                    app.EditFieldDur.Value = pulse_delta;
                    app.EditFieldFreq.Value = freq/10;
                    app.EditFieldGain.Value = gain;
                    delay = pulse_delta / 10 - 1;
                    vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                    vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
                    % we are using pulse_delta and delay to double the
                    % possible length of the vibration; delay is basically
                    % equal to the pulse_delta, allowing for a total of
                    % 2x2500 ms vibration.

                    app.InfoBox1.Value = strcat("Sent vibration with these characteristics: Freq = ", num2str(freq/10), "Hz, Gain = ", num2str(gain), ", Duration = ", num2str(pulse_delta), " ms.");
                    app.InfoBox1.Value = [app.InfoBox1.Value; "---"; "Now proceed by answering the questions below and delivering the Next Stimulation."];

                    app.StartSurveyButton.Enable = "off";
                    app.StartSurveyButton.Text = "Start Survey";
                end
            catch
                app.InfoBox1.Value = "Error occurred. Maybe Tactors not connected properly, please retry";
                app.StartSurveyButton.Enable = "off";
                app.StartSurveyButton.Text = "Start Survey";
            end

            scroll(app.InfoBox1,"bottom");
        end

        % Button pushed function: RepeatStimulationButton
        function RepeatStimulationButtonPushed(app, event)
            try
                freq = 10 * app.EditFieldFreq.Value;
                gain = app.EditFieldGain.Value;
                pulse_delta = app.EditFieldDur.Value / 2;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];

                vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
                t = strcat("Sent again vibration with these characteristics: Freq = ", num2str(freq/10), "Hz, Gain = ", num2str(gain), ", Duration = ", num2str(pulse_delta), " ms.");
                app.InfoBox1.Value = [app.InfoBox1.Value; "---"; "Answers to previous stimulation saved only once."; "---"; t];
                scroll(app.InfoBox1, "bottom");
            catch
                app.InfoBox1.Value = [app.InfoBox1.Value; "---"; "Something went wrong, and it shouldn't have..."];
            end
        end

        % Button pushed function: SaveNextStimulationButton_2
        function SaveNextStimulationButton_2Pushed(app, event)
%             try
                % We save the current answers and execute the next random vibration
                % Pull down lists are set up do give numeric ItemsData values
                % when chosen, for easier analysis later
                
                app.answers = [app.answers; app.EditFieldFreq.Value app.EditFieldGain.Value app.EditFieldDur.Value str2double(app.DropDown1.Value) str2double(app.DropDown2.Value) str2double(app.DropDown3.Value) str2double(app.DropDown4.Value) str2double(app.DropDown5.Value) str2double(app.DropDown6.Value) str2double(app.DropDown7.Value)];

                if app.dowefreq
                    freq = 10 * (60 + 5 * unidrnd(16));
                else
                    freq = 1000;
                end
                % Frequency is pulled from a discrete uniform distribution, from 60 to 140Hz, with 5Hz increments
                if app.doweampl
                    gain = 50 + 10 * unidrnd(20);
                    % Gain is pulled from a discrete uniform
                    % distribution, from 50 to 250, with increments
                    % of 10
                else
                    gain = app.EditFieldGain.Value;
                    % Gain is fixed, chosen as "strong but not too strong"
                end

                if app.time_variant
                        pulse_delta = ( 500 * unidrnd(6) )/2;
                        % Gain is pulled from a discrete uniform
                        % distribution, from 500 to 3000 ms, with increments
                        % of 500; it is divided by 2 because the delay
                        % doubles it again later (you can't deliver more
                        % than 2500 ms of vibration with no delay-doubling)
                    else
                        pulse_delta = app.EditFieldDur.Value / 2;
                        % Gain is fixed, pulled from the Time dial value
                end

                app.EditFieldDur.Value = pulse_delta;
                app.EditFieldFreq.Value = freq/10;
                app.EditFieldGain.Value = gain;
                delay = pulse_delta / 10 - 1;
                vib_index = [app.Tactor1CheckBox.Value app.Tactor2CheckBox.Value app.Tactor3CheckBox.Value];
                
                t = strcat("Sent vibration with these characteristics: Freq = ", num2str(freq/10), "Hz, Gain = ", num2str(gain), ", Duration = ", num2str(pulse_delta), " ms.");

                try
                    vibrateTactors(app,freq,gain,pulse_delta, delay, vib_index);
                catch
                    t = "Tactors disconnected or not responding properly. Reinitialize them.";
                end
                
                app.InfoBox1.Value = [app.InfoBox1.Value; "---"; "Saved answers to previous stimulation"; "---"; t];
%             catch
%                 app.InfoBox1.Value = "Something didn't actually work dude... Welp";
%             end

            scroll(app.InfoBox1, "bottom");
        end

        % Button pushed function: SaveSurveyAnswersButton
        function Phase1TactorsFinishButtonPushed(app, event)
            app.answers = [app.answers; app.EditFieldFreq.Value app.EditFieldGain.Value app.EditFieldDur.Value str2double(app.DropDown1.Value) str2double(app.DropDown2.Value) str2double(app.DropDown3.Value) str2double(app.DropDown4.Value) str2double(app.DropDown5.Value) str2double(app.DropDown6.Value) str2double(app.DropDown7.Value)];
            fnam = strcat("Phase_1_Tactors_Survey_Trial_", app.SurveyIdentifierEditField.Value);
            
            if isfile(strcat(app.path_1, "\", fnam, ".xlsx"))
                app.InfoBox1.Value = "Answers file already present, please rename it to back it up or delete it.";
            else
                answers_ = array2table(app.answers);
                answers_.Properties.VariableNames(1:10) = ["Freq" "Gain" "Duration" "Vibration" "Illusion" "Joint" "Direction" "Strength" "ROM" "Speed"];
                % should be ok saving like this
                writetable(answers_,strcat(app.path_1, "\", fnam, ".xlsx"));
                pause(1);
                app.answers = [];
                app.SaveNextStimulationButton_2.Enable = "off";
                app.RepeatStimulationButton.Enable = "off";
                app.SaveSurveyAnswersButton.Enable = "off";
                app.StartSurveyButton.Enable = "on";
                app.SurveyQuestionsPanel.Enable = 0;
                app.FreqHzKnob.Enable = 1;
                app.GainKnob.Enable = 1;
                app.OverwriteLastAnswersButton_2.Enable = 0;
                app.SurveyedVariablesPh12Panel.Enable = 1;

                dirs = dir(app.path_1);
                dirs = string({dirs.name})';
                pause(1)
                app.InfoBox1.Value = [strcat("Tactors Survey Number ", app.SurveyIdentifierEditField.Value, " saved. Here it is in today's directory:"); "---"; dirs];
            end
        end

        % Button pushed function: StartSurveyButton_2
        function Phase2GloveStartButtonPushed(app, event)
            try
                if app.StartSurveyButton_2.Text == "Start Survey"
                    
                    app.InfoBox2.Value = ["The stimulation-survey will start by pressing First Stimulation";
                        "---";
                        "The number of stimulations is not fixed.";
                        "---";
                        "For every stimulation frequency and torque are computed randomly.";
                        "---";
                        "Firstly set them up on the MYKI Drivers Console.";
                        "Secondly proceed to deliver vibration.";
                        "Then record glove data using the CyberGlove Stream ON/OFF toggle";
                        "Finally, answer the questions below.";
                        "Only then proceed with next Stimulation."
                        ];
                    app.StartSurveyButton_2.Text = "First Stimulation";
                    app.dowefreq = app.FrequencyCheckBox.Value;
                    app.doweampl = app.GainTorqueCheckBox.Value;
                    app.time_variant = app.TimeVariantCheckBox.Value;
                    app.currents = app.CurrentsCheckBox.Value;

                elseif app.StartSurveyButton_2.Text == "First Stimulation"
                    app.SaveNextStimulationButton.Enable = "on";
                    app.RepeatStimulationButton_2.Enable = "on";
                    app.SaveSurveyAnswersButton_2.Enable = "on";

                    app.PingCyberGloveButton.Enable = 0;
                    app.PositListBox.Enable = 0;
                    app.SavexlsxPositionButton.Enable = 0;
                    app.CyberGloveStreamOFFButton.Enable = 1;
                    app.OverwriteLastAnswersButton.Enable = 1;

                    app.SurveyQuestionsPanel_2.Enable = 1;

                    if app.time_variant
                        app.time = 500 * unidrnd(6);
                        % Time is pulled from a discrete uniform
                        % distribution, from 500 to 3000 ms, with increments
                        % of 500 (no delay-doubling here!)
                    else
                        app.time = app.EditFieldDur.Value;
                        % Time is fixed, pulled from the Time dial value
                    end

                    % Frequency is pulled from a discrete uniform distribution, from 50 to 110Hz, with 5Hz increments
                    if app.currents
                        if app.dowefreq
                            app.glove_freq = 50 + 5 * unidrnd(12);
                        else
                            app.glove_freq = 90;
                        end
                        % In case we want to change the frequency range
                        % with "currents" setup
                        if app.doweampl 
                            app.glove_ampl = 100 + 100 * unidrnd(19);
                            % amplitude/torque is pulled from discrete uniform
                            % distrib, from 5 to 40, 5 mA current increments
                        else
                            app.glove_ampl = 1500;
                            % Hard coded as middle ground amplitude current, review in meetings
                        end
                        text = strcat("Deliver vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Current = ", num2str(app.glove_ampl), " mA, Duration = ", num2str(app.time), " ms.");
                    else
                        if app.dowefreq
                            app.glove_freq = 50 + 5 * unidrnd(12);
                        else
                            app.glove_freq = 90;
                        end
                        if app.doweampl
                            app.glove_ampl = 5 + 5 * unidrnd(7);
                            % amplitude/torque is pulled from discrete uniform
                            % distrib, from 5 to 40, 5 microNm increments
                        else
                            app.glove_ampl = 30;
                            % Hard coded as middle ground amplitude, review in meetings
                        end
                        text = strcat("Deliver vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Torque = ", num2str(app.glove_ampl), " 10^(-6)Nm, Duration = ", num2str(app.time), " ms.");
                    end
                    
                    app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text; "---"; "Now proceed by recording CyberGlove Data and THEN answering the questions below"; "---"; "When done proceed with the Next Stimulation."];

                    app.StartSurveyButton_2.Enable = "off";
                    app.StartSurveyButton_2.Text = "Start Survey";
                end
            catch
                app.InfoBox2.Value = "Error occurred. Please try with a prayer or something.";
            end

            scroll(app.InfoBox2,"bottom");
        end

        % Button pushed function: SaveNextStimulationButton
        function SaveNextStimulationButtonPushed(app, event)
            if app.currents
                filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_C", num2str(app.glove_ampl), "_T", num2str(app.time), ".xlsx");
            else
                filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_A", num2str(app.glove_ampl), "_T", num2str(app.time), ".xlsx");
            end
            
            if exist(filnam, "file")            % only if last glove recording exists take the answers and proceed with next stimulation
                try
                    % We save the current answers and execute the next random vibration
                    % Pull down lists are set up do give numeric ItemsData values
                    % when chosen, for easier analysis later

                    app.answers = [app.answers; app.glove_freq app.glove_ampl app.time str2double(app.DropDown1_2.Value) str2double(app.DropDown2_2.Value) str2double(app.DropDown3_2.Value) str2double(app.DropDown4_2.Value) str2double(app.DropDown5_2.Value)];

                    if app.time_variant
                        app.time = 500 * unidrnd(6);
                        % Time is pulled from a discrete uniform
                        % distribution, from 500 to 3000 ms, with increments
                        % of 500 (no delay-doubling here!)
                    else
                        app.time = app.EditFieldDur.Value;
                        % Time is fixed, pulled from the Time dial value
                    end

                    % Frequency is pulled from a discrete uniform distribution, from 50 to 110Hz, with 5Hz increments
                    if app.currents
                        if app.dowefreq
                            app.glove_freq = 50 + 5 * unidrnd(12);
                        else
                            app.glove_freq = 90;
                        end
                        % In case we want to change the frequency range
                        % with "currents" setup
                        if app.doweampl
                            app.glove_ampl = 100 + 100 * unidrnd(19);
                            % amplitude/torque is pulled from discrete uniform
                            % distrib, from 5 to 40, 5 mA current increments
                        else
                            app.glove_ampl = 1500;
                            % Hard coded as middle ground amplitude current, review in meetings
                        end
                        text = strcat("Deliver vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Current = ", num2str(app.glove_ampl), " mA, Duration = ", num2str(app.time), " ms.");
                    else
                        if app.dowefreq
                            app.glove_freq = 50 + 5 * unidrnd(12);
                        else
                            app.glove_freq = 90;
                        end
                        if app.doweampl
                            app.glove_ampl = 5 + 5 * unidrnd(7);
                            % amplitude/torque is pulled from discrete uniform
                            % distrib, from 5 to 40, 5 microNm increments
                        else
                            app.glove_ampl = 30;
                            % Hard coded as middle ground amplitude, review in meetings
                        end
                        text = strcat("Deliver vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Torque = ", num2str(app.glove_ampl), " 10^(-6)Nm, Duration = ", num2str(app.time), " ms.");
                    end

                    app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text; "---"; "Now proceed by recording CyberGlove Data and THEN answering the questions below"; "---"; "When done proceed with the Next Stimulation."];

                catch
                    app.InfoBox2.Value = "Last recording broken, terminate the Survey and fix pls.";
                end
            else
                app.InfoBox2.Value = "Last recording broken or not existing, redo.";
            end

            scroll(app.InfoBox2, "bottom");
        end

        % Button pushed function: RepeatStimulationButton_2
        function RepeatStimulationButton_2Pushed(app, event)
            if app.currents
                text = strcat("Deliver again vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Current = ", num2str(app.glove_ampl), " mA, Duration = ", num2str(app.time), " ms.");
            else
                text = strcat("Deliver again vibration with these characteristics: Freq = ", num2str(app.glove_freq), "Hz, Torque = ", num2str(app.glove_ampl), " 10^(-6)Nm, Duration = ", num2str(app.time), " ms.");
            end
            app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text; "---"; "Now proceed by recording CyberGlove Data and THEN answering the questions below (old recording will be replaced)."; "---"; "When done proceed with the Next Stimulation."];
            scroll(app.InfoBox2,"bottom");
        end

        % Button pushed function: SaveSurveyAnswersButton_2
        function Phase2GloveFinishButtonPushed(app, event)
            if app.currents
                fnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Survey.xlsx");
                filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_C", num2str(app.glove_ampl), "_T", num2str(app.time), ".xlsx");
            else
                fnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Survey.xlsx");
                filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_A", num2str(app.glove_ampl), "_T", num2str(app.time), ".xlsx");
            end

            text = "There's an error in finding files. RIP.";

            if exist(filnam, "file")
                app.answers = [app.answers; app.glove_freq app.glove_ampl app.time str2double(app.DropDown1_2.Value) str2double(app.DropDown2_2.Value) str2double(app.DropDown3_2.Value) str2double(app.DropDown4_2.Value) str2double(app.DropDown5_2.Value)];
            if exist(fnam, "file")
                text = "Answers file already present, please rename it to save it or delete it.";             
            else
                answers_ = array2table(app.answers);
                if app.currents
                    answers_.Properties.VariableNames(1:8) = ["Freq" "Current" "Duration" "Vibration" "Illusion" "Joint" "Direction" "Strength"];
                else
                    answers_.Properties.VariableNames(1:8) = ["Freq" "Torque" "Duration" "Vibration" "Illusion" "Joint" "Direction" "Strength"];
                end
                % should be ok saving like this
                writetable(answers_, fnam);
                pause(1);
                app.answers = [];
                app.SaveNextStimulationButton.Enable = "off";
                app.RepeatStimulationButton_2.Enable = "off";
                app.SaveSurveyAnswersButton_2.Enable = "off";
                app.StartSurveyButton_2.Enable = "on";
                app.SurveyQuestionsPanel_2.Enable = 0;
                app.PingCyberGloveButton.Enable = 1;
                app.CyberGloveStreamOFFButton.Enable = 0;
                app.PositListBox.Enable = 1;
                app.SavexlsxPositionButton.Enable = 1;
                app.OverwriteLastAnswersButton.Enable = 0;

                dirs = dir(app.path_2);
                dirs = string({dirs.name})';
                pause(1)
                text = [strcat("CyberGlove Survey Number ", app.SurveyIdentifierEditField_2.Value, " saved. Here it is in today's directory:"); "---"; dirs];
            end
            end

            app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text];
            scroll(app.InfoBox2,"bottom");
        end

        % Button pushed function: CreateTrialDirectoryButton
        function CreateTrialDirectoryButtonPushed(app, event)
            if ~isfolder(app.path_3)
                mkdir(app.path_3);
                addpath(app.path_3);
            end
            
            app.this_trial_path_3 = strcat(app.path_3, "\Trial_", app.TrialIdentifierEditField.Value, "_", app.TrialVariableDropDown.Value);
            if ~isfolder(app.this_trial_path_3)
                mkdir(app.this_trial_path_3);
                addpath(app.this_trial_path_3);
            else
                addpath(app.this_trial_path_3);
                app.InfoBox3.Value = "Currently chosen trial number and variable directory already existing, all files will be overwritten!";
                pause(3);
            end
            
            dirs = dir(app.path_3);
            dirs = string({dirs.name})';

            if isfolder(app.path_3)
                dirs_ = dir(app.this_trial_path_3);
                dirs_ = string({dirs_.name})';
                app.InfoBox3.Value = [dirs; "---"; "Trial's folder created. Here's what's in it:"; dirs_; "---"; "--> Now you can proceed with the questionnarie with some random stimulations. <--"];
            else
                app.InfoBox3.Value = [dirs; "---"; "--> Check above if the folder for today's questionnare is present. If not, create it. <--"];
            end

            scroll(app.InfoBox3,"bottom");
        end

        % Button pushed function: StartSequenceButton
        function StartSequenceButtonPushed(app, event)
            % State Machine Implementation for this button
            % First state
            if isfolder(char(app.this_trial_path_3))
                if app.StartSequenceButton.Text == "Start Sequence"
                    app.currents = app.CurrentsCheckBox.Value;
                    switch app.TrialVariableDropDown.Value
                        case "Frequency"
                            axis_unit = " Hz";
                            app.MoreIntenseButton.Text = "More Intense";
                            app.LessIntenseButton.Text = "Less Intense";
                            app.SameIntensityButton.Text = "Same Intensity";
                            lable = "Frequency";
                        case "Torque"
                            axis_unit = " exp(-6) N*m";
                            app.MoreIntenseButton.Text = "Vibration Sensed";
                            app.LessIntenseButton.Text = "No Vibration Sensed";
                            app.SameIntensityButton.Text = "Not Sure";
                            lable = "Torque";
                        case "Impulse_Duration"
                            axis_unit = " ms";
                            app.MoreIntenseButton.Text = "Vibration Sensed";
                            app.LessIntenseButton.Text = "No Vibration Sensed";
                            app.SameIntensityButton.Text = "Not Sure";
                            lable = "Impulse Duration";
                        case "Current_Upper"
                            axis_unit = " mA";
                            app.MoreIntenseButton.Text = "More Intense / NO Pain";
                            app.LessIntenseButton.Text = "Less Intense / Pain";
                            app.SameIntensityButton.Text = "Not Sure";
                            lable = "Current UpperLimit";
                        case "Current_Lower"
                            axis_unit = " mA";
                            app.MoreIntenseButton.Text = "Vibration Sensed";
                            app.LessIntenseButton.Text = "No Vibration Sensed";
                            app.SameIntensityButton.Text = "Not Sure";
                            lable = "Current LowerLimit";
                    end
                    ylabel(app.UIAxes, (lable + axis_unit));
                    app.TrialIdentifierEditField.Enable = "off";
                    app.TrialVariableDropDown.Enable = "off";
                    app.CreateTrialDirectoryButton.Enable = "off";
                    app.SaveDataButton.Enable = "off";
                    app.SaveImageButton.Enable = "off";
                    app.SavebothDataandImageButton.Enable = "off";
                    app.AbortSequenceButton.Enable = "on";
                    app.aborted = 0;
                    app.InfoBox3.Value = ["Sequence Starts when button Deliver First Stimulation is pressed";
                        "---";
                        strcat("You have chosen to run Trial number ", app.TrialIdentifierEditField.Value, " for the variable ", app.TrialVariableDropDown.Value);
                        "---";
                        "Make sure to check it hasn't been run before, or it will be replaced."];
                    app.StartSequenceButton.Text = "Deliver First Stimulation";

                    % Second State
                elseif app.StartSequenceButton.Text == "Deliver First Stimulation"
                    % app.Phase3AdaptiveThresholdsTabButtonDown(app);
                    adaptive_sequence3(app)
                    
                    % Third State
                elseif app.StartSequenceButton.Text == "Deliver Second Stimulation"
                    % app.Phase3AdaptiveThresholdsTabButtonDown(app);
                    adaptive_sequence3(app)

                end
            else
                app.InfoBox3.Value = "Data Directory MUST be created before Start Sequence, use the button on the left and try again!";
            end
        end

        % Button pushed function: ShowDirectoriesButton
        function ShowDirectoriesButtonPushed(app, event)
            dirs = dir;
            dirs = string({dirs.name})';            
            
            if isfolder(app.path_3)
                dirs_ = dir(app.path_3);
                dirs_ = string({dirs_.name})';
                app.InfoBox3.Value = [dirs; "---"; strcat("Today's Subject ", app.subj, " Phase 3 folder is present. Here's what's in it:"); dirs_; "---"];
            else
                app.InfoBox3.Value = [dirs; "---"; "--> Check above if the folder for today's questionnare is present. If not, create it. <--"];
            end
        end

        % Button pushed function: MoreIntenseButton
        function MoreIntenseButtonPushed(app, event)
            % Keep going normally with the sequence
            app.Z = 1;
            adaptive_sequence3(app)
            app.same = 0;
        end

        % Button pushed function: LessIntenseButton
        function LessIntenseButtonPushed(app, event)
            % Change Z indicating a flex point in the sequence.
            % The sequence goes up roughly two steps and the slope softens.

            app.Z = 0;
            adaptive_sequence3(app)
            app.same = 0;
        end

        % Button pushed function: SameIntensityButton
        function SameIntensityButtonPushed(app, event)
            % If answer "same" is given 3 times, register as a "less"
            if app.same == 2
                app.Z = 0;
                adaptive_sequence3(app);
                app.same = 0;
            else
                app.Z = 1;
                adaptive_sequence3(app)
                app.same = app.same + 1;
            end
        end

        % Button down function: Phase3AdaptiveThresholdsTab
        function Phase3AdaptiveThresholdsTabButtonDown(app, event)
            % simple graph setup
            grid(app.UIAxes,'on');
            xticks(app.UIAxes, 0:1:length(app.var)+1);
            xlabel(app.UIAxes, "Stimulus Number")
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            save_phase3(app, true, false)
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            save_phase3(app, false, true)
        end

        % Button pushed function: SavebothDataandImageButton
        function SavebothDataandImageButtonPushed(app, event)
            save_phase3(app, true, true)
        end

        % Button pushed function: CloseTactorInterfaceButton
        function CloseTactorInterfaceButtonPushed(app, event)
            KillTactors(app)
            pause(1);
            app.InitializeTactorInterfaceButton.Enable = 1;
            app.CloseTactorInterfaceButton.Enable = 0;
            app.FIREButton.Enable = 0;
        end

        % Button pushed function: ShowDirectoriesButtonPhase1
        function ShowDirectoriesButtonPhase1Pushed(app, event)
            dirs = dir;
            dirs = string({dirs.name})';

            if isfolder(app.path_1)
                dirs_ = dir(app.path_1);
                dirs_ = string({dirs_.name})';
                app.InfoBox1.Value = [dirs; "---"; strcat("Today's Subject ", app.subj, " Phase 1 folder is present. Here's what's in it:"); dirs_; "---"; "Choose a Survey Number that's not already used before starting Survey."];
            else
                app.InfoBox1.Value = [dirs; "---"; "--> Check above if the folder for today's questionnare is present. If not, create it. <--"];
            end

            scroll(app.InfoBox1,"bottom");
        end

        % Button down function: COMPortsUpdatePanel
        function COMPortsUpdatePanelButtonDown(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.PortaListBox_2.Items = serials;
        end

        % Button pushed function: InitializeGloveInterfaceButton
        function InitializeGloveInterfaceButtonPushed(app, event)
            try
                app.com_port = app.PortaListBox_2.Value;
                if ~isfile("Cyberglove.m")
                    err_msg = msgbox("Cyberglove.m file dependancy not found in main directory, please add and retry","Error","warn");
                    uiwait(err_msg);
                    pause(1);
                    return
                end
                app.glove = Cyberglove(app.com_port);
                app.InfoBox2.Value = ["CyberGlove Connection Successful.";
                    "---";
                    "Proceed by using the panel on the bottom left to save at least 5 glove readings for the positions:";
                    "FLEX: hand closed in a fist, with long fingers wrapped around the abducted and flexed thumb;";
                    "EXT: hand fully extended, all fingers are extended and abducted wide apart.";
                    "---";
                    "Save for each position.";
                    "---";
                    "Once finished with the calibration data proceed with the Survey, bottom right panel."];
                app.CloseGloveInterfaceButton.Enable = 1;
                app.InitializeGloveInterfaceButton.Enable = 0;
                app.CyberGloveCalibrationDataPanel.Enable = 1;
                app.ProprioceptiveIllusionSurveyPanel_2.Enable = 1;
            catch
                app.InfoBox2.Value = "CyberGlove Connection Failed.";
            end
        end

        % Button pushed function: CloseGloveInterfaceButton
        function CloseGloveInterfaceButtonPushed(app, event)
            KillGlove(app);
            app.CloseGloveInterfaceButton.Enable = 0;
            app.InitializeGloveInterfaceButton.Enable = 1;
            app.InfoBox2.Value = [string(app.InfoBox2.Value); "---"; "CyberGlove Interface killed. You can try to reconnect."];
            app.CyberGloveCalibrationDataPanel.Enable = 0;
            app.ProprioceptiveIllusionSurveyPanel_2.Enable = 0;
        end

        % Button pushed function: ShowDirectoriesButtonPhase2
        function ShowDirectoriesButtonPhase2Pushed(app, event)
            dirs = dir;
            dirs = string({dirs.name})';

            if isfolder(app.path_2)
                dirs_ = dir(app.path_2);
                dirs_ = string({dirs_.name})';
                app.InfoBox2.Value = [dirs; "---"; strcat("Today's Subject ", app.subj, " Phase 2 folder is present. Here's what's in it:"); dirs_; "---"; "Choose a Survey Number that's not already used before Starting Survey."];
            else
                app.InfoBox2.Value = [dirs; "---"; "--> Check above if the folder for today's questionnare is present. If not, create it. <--"];
            end

            scroll(app.InfoBox2,"bottom");
        end

        % Button pushed function: PingCyberGloveButton
        function PingCyberGloveButtonPushed(app, event)
            pause(0.6)
            write(app.glove.serial_p, 'G', 'uint8');
            pause(0.2)
            if app.glove.serial_p.NumBytesAvailable ~= 20
                app.InfoBox2.Value = "Glove not responding. Close interface and reopen it.<--";
            else
                data = read(app.glove.serial_p, app.glove.serial_p.NumBytesAvailable, 'uint8');
                if isempty(app.glove_cal_pulls)
                    app.glove_cal_pulls = data(2:end-1);
                else
                    app.glove_cal_pulls = [app.glove_cal_pulls; data(2:end-1)];
                end
                temp_tab = array2table(app.glove_cal_pulls);
                % handling as a table just to post values in UITable
                app.UITable.Data = temp_tab;
            end
        end

        % Button pushed function: SavexlsxPositionButton
        function SavexlsxPositionButtonPushed(app, event)
            if ~isa(app.glove_cal_pulls, "table")
                app.glove_cal_pulls = array2table(app.glove_cal_pulls);
                app.glove_cal_pulls.Properties.VariableNames(1:18) = app.DataTags;
            end
            
            writetable(app.glove_cal_pulls,strcat(app.path_2,"\Cal_pos_", app.PositListBox.Value,".xlsx"),"WriteMode","overwrite");

            app.glove_cal_pulls = [];
            app.UITable.Data = table();

            text = strcat("Saved calibration data for ", app.PositListBox.Value, ".");
            app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text];
            scroll(app.InfoBox2,"bottom");
        end

        % Value changed function: CyberGloveStreamOFFButton
        function CyberGloveStreamOFFButtonValueChanged(app, event)
            value = app.CyberGloveStreamOFFButton.Value;
            
            switch value
                case 1
                    % Start streaming
                    StartAcquisition(app.glove);
                    
                    % rename button in on
                    app.CyberGloveStreamOFFButton.Text = "CyberGlove Stream ON";
                    app.InfoBox2.Value = [app.InfoBox2.Value; "---"; "CyberGlove Stream ON"];
                case 0
                    % close streaming
                    StopAcquisition(app.glove);
                                        
                    % take output and save-rename with current freq and torque
                    stream = load('streamed.txt');
                    stream = array2table(stream(:,1:end-1));
                    stream.Properties.VariableNames = app.DataTags;
                    
                    mkdir(strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings"));
                    if app.currents
                        filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_C", num2str(app.glove_ampl),"_T", num2str(app.time), ".xlsx");
                    else
                        filnam = strcat(app.path_2, "\Phase2_Glove_Trial_", app.SurveyIdentifierEditField_2.Value, "_Recordings\Track_", num2str(1+size(app.answers,1)), "_F", num2str(app.glove_freq), "_A", num2str(app.glove_ampl),"_T", num2str(app.time), ".xlsx");
                    end
                    writetable(stream, filnam,"WriteMode","overwrite");

                    % erase temporary files
                    delete streamed.txt
                    clear stream
                    
                    % rename button in off
                    app.CyberGloveStreamOFFButton.Text = "CyberGlove Stream OFF";
                    app.InfoBox2.Value = [app.InfoBox2.Value; "---"; "CyberGlove Stream OFF - Check if the stream file exists."];
            end
            scroll(app.InfoBox2,"bottom");
        end

        % Button down function: COMPortsUpdatePanel_2
        function COMPortsUpdatePanel_2ButtonDown(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.PortaListBox_2.Items = serials;
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            if strcmp(value, '0')
                app.DropDown5.Value = '0';
                app.DropDown6.Value = '0';
                app.DropDown7.Value = '0';
            end
        end

        % Button pushed function: EditSubjectsJointsListButton, 
        % ...and 2 other components
        function EditSubjectsJointsListButtonPushed(app, event)
            fig = uifigure;
            fig.Position(3:4) = [250 336];
            fig.Name = 'Joints List';

            uilabel(fig,"Text","Insert Implanted Joints for chosen Subject","Position",[10 282 230 44]);

            loglist = uilistbox(fig,...
                "Items",app.joints,...
                "Position",[10 50 230 200]);

            namefld = uieditfield(fig,"text",...
                "Position",[10 260 230 22],...
                "ValueChangedFcn",@(namefld,event) nValChanged(namefld,event,loglist)); %#ok<NASGU> 

            b = uibutton(fig,"Position",[10 10 230 30],'ButtonPushedFcn', @(b,event) ButtonPushedFcn(b,loglist,app,fig));
            b.Text = 'Save & Exit';

            % Create ValueChangedFcn callback
            function nValChanged(namefld,event,loglist) %#ok<INUSD> 
                newvalue = {event.Value};
                if ~strcmp(newvalue, '')                % reject empty entries
                    loglist.Items(end+1) = newvalue;
                end
            end

            function ButtonPushedFcn(b,loglist,app,fig) %#ok<INUSD> 
                app.joints = loglist.Items;
                writecell(app.joints,strcat("Subj_", app.subj,"\JointsList.txt"));
                app.DropDown3.Items = app.joints;
                app.DropDown3_2.Items = app.joints;
                delete(fig)
            end
        end

        % Button down function: CyberGloveDataPanel
        function CyberGloveDataPanelButtonDown(app, event)
            app.glove_cal_pulls = [];
            app.UITable.Data = table();
        end

        % Button pushed function: DeleteLastStepButton
        function DeleteLastStepButtonPushed(app, event)
            app.var = app.var(1:end-1);

            if app.Z == 0
                app.Z = 1;
                app.m = app.m-1;
            end     % This takes care of the _negative_answer_ flipping and curving

            if app.same > 0
                app.same = app.same-1;
            end     % This decreases _same_answer_ only if it was in streak
                
            draw_sequence3(app)
        end

        % Button pushed function: OverwriteLastAnswersButton
        function OverwriteLastAnswersButtonPushed(app, event)
            app.answers(end,4:end) = [str2double(app.DropDown1_2.Value) str2double(app.DropDown2_2.Value) str2double(app.DropDown3_2.Value) str2double(app.DropDown4_2.Value) str2double(app.DropDown5_2.Value)];
            if app.currents
                text = strcat("Overwritten answers for last vibration with these characteristics: Freq = ", num2str(app.answers(end,1)), "Hz, Current = ", num2str(app.answers(end,2)), " mA.");
            else
                text = strcat("Overwritten answers for last vibration with these characteristics: Freq = ", num2str(app.answers(end,1)), "Hz, Torque = ", num2str(app.answers(end,2)), " 10^(-6)Nm.");
            end
            app.InfoBox2.Value = [app.InfoBox2.Value; "---"; text];
        end

        % Button pushed function: OverwriteLastAnswersButton_2
        function OverwriteLastAnswersButton_2Pushed(app, event)
            app.answers(end,4:end) = [str2double(app.DropDown1.Value) str2double(app.DropDown2.Value) str2double(app.DropDown3.Value) str2double(app.DropDown4.Value) str2double(app.DropDown5.Value) str2double(app.DropDown6.Value) str2double(app.DropDown7.Value)];
            text = strcat("Overwritten answers for last vibration with these characteristics: Freq = ", num2str(app.answers(end,1)), "Hz, Gain = ", num2str(app.answers(end,2)));
            app.InfoBox1.Value = [app.InfoBox2.Value; "---"; text];
        end

        % Button pushed function: AbortSequenceButton
        function AbortSequenceButtonPushed(app, event)
            app.var  = [app.var zeros(1,9)];
            app.aborted = 1;
            adaptive_sequence3(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'F1MYKI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1TrainingandCalibrationTab = uitab(app.TabGroup);
            app.Phase1TrainingandCalibrationTab.Title = 'Phase 1 - Training and Calibration';
            app.GrigliaMAIN = uigridlayout(app.Phase1TrainingandCalibrationTab);
            app.ProprioceptiveIllusionSurveyPanel = uipanel(app.GrigliaMAIN);
            app.ProprioceptiveIllusionSurveyPanel.Title = 'Proprioceptive Illusion Survey';
            app.GridLayout3 = uigridlayout(app.ProprioceptiveIllusionSurveyPanel);
            app.StartSurveyButton = uibutton(app.GridLayout3, 'push');
            app.StartSurveyButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsStartButtonPushed, true);
            app.StartSurveyButton.Text = 'Start Survey';
            app.SaveSurveyAnswersButton = uibutton(app.GridLayout3, 'push');
            app.SaveSurveyAnswersButton.ButtonPushedFcn = createCallbackFcn(app, @Phase1TactorsFinishButtonPushed, true);
            app.SaveSurveyAnswersButton.Enable = 'off';
            app.SaveSurveyAnswersButton.Text = {'Save Survey'; 'Answers'};
            app.SaveNextStimulationButton_2 = uibutton(app.GridLayout3, 'push');
            app.SaveNextStimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveNextStimulationButton_2Pushed, true);
            app.SaveNextStimulationButton_2.Enable = 'off';
            app.SaveNextStimulationButton_2.Text = {'Save & Next'; 'Stimulation'};
            app.RepeatStimulationButton = uibutton(app.GridLayout3, 'push');
            app.RepeatStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimulationButtonPushed, true);
            app.RepeatStimulationButton.Enable = 'off';
            app.RepeatStimulationButton.Text = {'Repeat'; 'Stimulation'};
            app.SurveyQuestionsPanel = uipanel(app.GridLayout3);
            app.SurveyQuestionsPanel.Enable = 'off';
            app.SurveyQuestionsPanel.Title = 'Survey Questions';
            app.GridLayout17 = uigridlayout(app.SurveyQuestionsPanel);
            app.DidyoufeelatactilevibrationLabel = uilabel(app.GridLayout17);
            app.DidyoufeelatactilevibrationLabel.Text = 'Did you feel a tactile vibration?';
            app.DidyoufeelasensationofmovementLabel = uilabel(app.GridLayout17);
            app.DidyoufeelasensationofmovementLabel.Text = 'Did you feel a sensation of movement?';
            app.InwhichdirectionwasthatjoingmovingLabel = uilabel(app.GridLayout17);
            app.InwhichdirectionwasthatjoingmovingLabel.Text = 'In which direction was that joing moving?';
            app.WhatjointdidyoufeelmoveLabel = uilabel(app.GridLayout17);
            app.WhatjointdidyoufeelmoveLabel.Text = 'What joint did you feel move?';
            app.DefinethestrengthvividnessofthemovementsensationLabel = uilabel(app.GridLayout17);
            app.DefinethestrengthvividnessofthemovementsensationLabel.Text = 'Define the strength, vividness of the movement sensation';
            app.DefinethedistancethismovementspannedontoLabel = uilabel(app.GridLayout17);
            app.DefinethedistancethismovementspannedontoLabel.Text = 'Define the distance this movement spanned onto';
            app.DefinethespeedofthissensedmovementLabel = uilabel(app.GridLayout17);
            app.DefinethespeedofthissensedmovementLabel.Text = 'Define the speed of this sensed movement';
            app.DropDown1 = uidropdown(app.GridLayout17);
            app.DropDown1.Items = {'Yes', 'No'};
            app.DropDown1.ItemsData = {'1', '0'};
            app.DropDown1.Placeholder = 'Choose Here';
            app.DropDown1.Value = '1';
            app.DropDown2 = uidropdown(app.GridLayout17);
            app.DropDown2.Items = {'Yes', 'No'};
            app.DropDown2.ItemsData = {'1', '0'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Placeholder = 'Choose Here';
            app.DropDown2.Value = '1';
            app.DropDown4 = uidropdown(app.GridLayout17);
            app.DropDown4.Items = {'Flexion', 'Extension'};
            app.DropDown4.ItemsData = {'1', '2', '3'};
            app.DropDown4.Placeholder = 'Choose Here';
            app.DropDown4.Value = '1';
            app.DropDown5 = uidropdown(app.GridLayout17);
            app.DropDown5.Items = {'No Sensation', '1', '2', '3', '4', '5'};
            app.DropDown5.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown5.Placeholder = 'Choose Here';
            app.DropDown5.Value = '0';
            app.DropDown6 = uidropdown(app.GridLayout17);
            app.DropDown6.Items = {'No Sensation', '1', '2', '3', '4', '5'};
            app.DropDown6.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown6.Placeholder = 'Choose Here';
            app.DropDown6.Value = '0';
            app.DropDown7 = uidropdown(app.GridLayout17);
            app.DropDown7.Items = {'No Sensation', '1', '2', '3', '4', '5'};
            app.DropDown7.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown7.Placeholder = 'Choose Here';
            app.DropDown7.Value = '0';
            app.DropDown3 = uidropdown(app.GridLayout17);
            app.DropDown3.Items = {'None'};
            app.DropDown3.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.DropDown3.Placeholder = 'Add Joints in code';
            app.DropDown3.Value = '0';
            app.OverwriteLastAnswersButton_2 = uibutton(app.GridLayout3, 'push');
            app.OverwriteLastAnswersButton_2.ButtonPushedFcn = createCallbackFcn(app, @OverwriteLastAnswersButton_2Pushed, true);
            app.OverwriteLastAnswersButton_2.Enable = 'off';
            app.OverwriteLastAnswersButton_2.Text = {'Overwrite'; 'Last Answers'};
            app.VibrationControlsPanel = uipanel(app.GrigliaMAIN);
            app.VibrationControlsPanel.Title = 'Vibration Controls';
            app.GridLayout2 = uigridlayout(app.VibrationControlsPanel);
            app.EditFieldFreq = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldFreq.Limits = [50 140];
            app.EditFieldFreq.ValueDisplayFormat = '%.0f';
            app.EditFieldFreq.ValueChangedFcn = createCallbackFcn(app, @EditFieldFreqValueChanged, true);
            app.EditFieldFreq.Value = 60;
            app.GainKnob = uiknob(app.GridLayout2, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.ValueChangedFcn = createCallbackFcn(app, @GainKnobValueChanged, true);
            app.GainKnob.Value = 50;
            app.EditFieldGain = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldGain.Limits = [50 255];
            app.EditFieldGain.ValueDisplayFormat = '%.0f';
            app.EditFieldGain.ValueChangedFcn = createCallbackFcn(app, @EditFieldGainValueChanged, true);
            app.EditFieldGain.Value = 50;
            app.EditFieldDur = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldDur.Limits = [100 5000];
            app.EditFieldDur.ValueDisplayFormat = '%.0f';
            app.EditFieldDur.ValueChangedFcn = createCallbackFcn(app, @EditFieldDurValueChanged, true);
            app.EditFieldDur.Value = 1000;
            app.DuratamsKnob = uiknob(app.GridLayout2, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.ValueChangedFcn = createCallbackFcn(app, @DuratamsKnobValueChanged, true);
            app.DuratamsKnob.Value = 1000;
            app.FreqHzLabel = uilabel(app.GridLayout2);
            app.FreqHzLabel.Text = 'Freq (Hz)';
            app.GainLabel = uilabel(app.GridLayout2);
            app.GainLabel.Text = 'Gain';
            app.TimemsLabel = uilabel(app.GridLayout2);
            app.TimemsLabel.Text = 'Time (ms)';
            app.FreqHzKnob = uiknob(app.GridLayout2, 'continuous');
            app.FreqHzKnob.Limits = [50 140];
            app.FreqHzKnob.MajorTicks = [50 60 70 80 90 100 110 120 130 140];
            app.FreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @FreqHzKnobValueChanged, true);
            app.FreqHzKnob.Value = 60;
            app.SurveyedVariablesPh12Panel = uipanel(app.GridLayout2);
            app.SurveyedVariablesPh12Panel.Tooltip = {'These Controls are used for both Phase 1 and Phase 2 Surveys'};
            app.SurveyedVariablesPh12Panel.Title = 'Surveyed Variables (Ph. 1 & 2)';
            app.GridLayout19 = uigridlayout(app.SurveyedVariablesPh12Panel);
            app.TimeVariantCheckBox = uicheckbox(app.GridLayout19);
            app.TimeVariantCheckBox.Text = 'Time Variant';
            app.TimeVariantCheckBox.Value = true;
            app.FrequencyCheckBox = uicheckbox(app.GridLayout19);
            app.FrequencyCheckBox.Text = 'Frequency';
            app.FrequencyCheckBox.Value = true;
            app.GainTorqueCheckBox = uicheckbox(app.GridLayout19);
            app.GainTorqueCheckBox.Text = 'Gain/Torque';
            app.GainTorqueCheckBox.Value = true;
            app.CurrentsCheckBox = uicheckbox(app.GridLayout19);
            app.CurrentsCheckBox.Text = 'Currents';
            app.CurrentsCheckBox.Value = true;
            app.FIREButton = uibutton(app.GridLayout2, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Enable = 'off';
            app.FIREButton.Text = 'FIRE';
            app.TactorsONPanel = uipanel(app.GridLayout2);
            app.TactorsONPanel.Title = 'Tactors ON';
            app.GridLayout6 = uigridlayout(app.TactorsONPanel);
            app.Tactor1CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor1CheckBox.Text = 'Tactor 1';
            app.Tactor1CheckBox.Value = true;
            app.Tactor2CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor2CheckBox.Text = 'Tactor 2';
            app.Tactor3CheckBox = uicheckbox(app.GridLayout6);
            app.Tactor3CheckBox.Text = 'Tactor 3';
            app.InfoBoxInitializationPanel = uipanel(app.GrigliaMAIN);
            app.InfoBoxInitializationPanel.Title = 'Info Box & Initialization';
            app.GridLayout16 = uigridlayout(app.InfoBoxInitializationPanel);
            app.InfoBox1 = uitextarea(app.GridLayout16);
            app.InfoBox1.Editable = 'off';
            app.COMPortsUpdatePanel = uipanel(app.GridLayout16);
            app.COMPortsUpdatePanel.Title = 'COM Ports - Update';
            app.COMPortsUpdatePanel.ButtonDownFcn = createCallbackFcn(app, @COMPortsUpdatePanelButtonDown, true);
            app.GridLayout8 = uigridlayout(app.COMPortsUpdatePanel);
            app.PortaListBox = uilistbox(app.GridLayout8);
            app.PortaListBox.Items = {};
            app.PortaListBox.Value = {};
            app.InitializeTactorInterfaceButton = uibutton(app.GridLayout16, 'push');
            app.InitializeTactorInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeTactorInterfaceButtonPushed, true);
            app.InitializeTactorInterfaceButton.Text = {'Initialize'; 'Tactor Interface'};
            app.CloseTactorInterfaceButton = uibutton(app.GridLayout16, 'push');
            app.CloseTactorInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CloseTactorInterfaceButtonPushed, true);
            app.CloseTactorInterfaceButton.Enable = 'off';
            app.CloseTactorInterfaceButton.Text = {'Close'; 'Tactor Interface'};
            app.ShowDirectoriesButtonPhase1 = uibutton(app.GridLayout16, 'push');
            app.ShowDirectoriesButtonPhase1.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPhase1Pushed, true);
            app.ShowDirectoriesButtonPhase1.Text = 'Show Directories';
            app.EditSubjectsJointsListButton = uibutton(app.GridLayout16, 'push');
            app.EditSubjectsJointsListButton.ButtonPushedFcn = createCallbackFcn(app, @EditSubjectsJointsListButtonPushed, true);
            app.EditSubjectsJointsListButton.Text = 'Edit Subject''s Joints List';
            app.SurveyIdentifierEditField_3Label = uilabel(app.GridLayout16);
            app.SurveyIdentifierEditField_3Label.Text = 'Survey Identifier:';
            app.SurveyIdentifierEditField = uieditfield(app.GridLayout16, 'text');
            app.SurveyIdentifierEditField.Placeholder = 'Joint ID and Trial #';
            app.Phase2GloveTrackingTab = uitab(app.TabGroup);
            app.Phase2GloveTrackingTab.Title = 'Phase 2 - Glove Tracking';
            app.GridLayout18 = uigridlayout(app.Phase2GloveTrackingTab);
            app.InfoBoxInitializationPanel_2 = uipanel(app.GridLayout18);
            app.InfoBoxInitializationPanel_2.Title = 'Info Box & Initialization';
            app.GridLayout16_2 = uigridlayout(app.InfoBoxInitializationPanel_2);
            app.InfoBox2 = uitextarea(app.GridLayout16_2);
            app.InfoBox2.Editable = 'off';
            app.COMPortsUpdatePanel_2 = uipanel(app.GridLayout16_2);
            app.COMPortsUpdatePanel_2.Title = 'COM Ports - Update';
            app.COMPortsUpdatePanel_2.ButtonDownFcn = createCallbackFcn(app, @COMPortsUpdatePanel_2ButtonDown, true);
            app.GridLayout8_2 = uigridlayout(app.COMPortsUpdatePanel_2);
            app.PortaListBox_2 = uilistbox(app.GridLayout8_2);
            app.PortaListBox_2.Items = {};
            app.PortaListBox_2.Value = {};
            app.InitializeGloveInterfaceButton = uibutton(app.GridLayout16_2, 'push');
            app.InitializeGloveInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeGloveInterfaceButtonPushed, true);
            app.InitializeGloveInterfaceButton.Text = {'Initialize'; 'Glove Interface'};
            app.CloseGloveInterfaceButton = uibutton(app.GridLayout16_2, 'push');
            app.CloseGloveInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @CloseGloveInterfaceButtonPushed, true);
            app.CloseGloveInterfaceButton.Enable = 'off';
            app.CloseGloveInterfaceButton.Text = {'Close'; 'Glove Interface'};
            app.ShowDirectoriesButtonPhase2 = uibutton(app.GridLayout16_2, 'push');
            app.ShowDirectoriesButtonPhase2.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPhase2Pushed, true);
            app.ShowDirectoriesButtonPhase2.Text = 'Show Directories';
            app.EditSubjectsJointsListButton_2 = uibutton(app.GridLayout16_2, 'push');
            app.EditSubjectsJointsListButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditSubjectsJointsListButtonPushed, true);
            app.EditSubjectsJointsListButton_2.Text = 'Edit Subject''s Joints List';
            app.SurveyIdentifierEditFieldLabel = uilabel(app.GridLayout16_2);
            app.SurveyIdentifierEditFieldLabel.Text = 'Survey Identifier:';
            app.SurveyIdentifierEditField_2 = uieditfield(app.GridLayout16_2, 'text');
            app.SurveyIdentifierEditField_2.Placeholder = 'Joint ID and Trial #';
            app.ProprioceptiveIllusionSurveyPanel_2 = uipanel(app.GridLayout18);
            app.ProprioceptiveIllusionSurveyPanel_2.Enable = 'off';
            app.ProprioceptiveIllusionSurveyPanel_2.Title = 'Proprioceptive Illusion Survey';
            app.GridLayout3_2 = uigridlayout(app.ProprioceptiveIllusionSurveyPanel_2);
            app.StartSurveyButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.StartSurveyButton_2.ButtonPushedFcn = createCallbackFcn(app, @Phase2GloveStartButtonPushed, true);
            app.StartSurveyButton_2.Text = 'Start Survey';
            app.SaveSurveyAnswersButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.SaveSurveyAnswersButton_2.ButtonPushedFcn = createCallbackFcn(app, @Phase2GloveFinishButtonPushed, true);
            app.SaveSurveyAnswersButton_2.Enable = 'off';
            app.SaveSurveyAnswersButton_2.Text = {'Save Survey'; 'Answers'};
            app.SaveNextStimulationButton = uibutton(app.GridLayout3_2, 'push');
            app.SaveNextStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNextStimulationButtonPushed, true);
            app.SaveNextStimulationButton.Enable = 'off';
            app.SaveNextStimulationButton.Text = {'Save & Next'; 'Stimulation'};
            app.RepeatStimulationButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.RepeatStimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RepeatStimulationButton_2Pushed, true);
            app.RepeatStimulationButton_2.Enable = 'off';
            app.RepeatStimulationButton_2.Text = {'Repeat'; 'Stimulation'};
            app.SurveyQuestionsPanel_2 = uipanel(app.GridLayout3_2);
            app.SurveyQuestionsPanel_2.Enable = 'off';
            app.SurveyQuestionsPanel_2.Title = 'Survey Questions';
            app.GridLayout17_2 = uigridlayout(app.SurveyQuestionsPanel_2);
            app.DidyoufeelatactilevibrationLabel_2 = uilabel(app.GridLayout17_2);
            app.DidyoufeelatactilevibrationLabel_2.Text = 'Did you feel a tactile vibration?';
            app.DidyoufeelasensationofmovementLabel_2 = uilabel(app.GridLayout17_2);
            app.DidyoufeelasensationofmovementLabel_2.Text = 'Did you feel a sensation of movement?';
            app.InwhichdirectionwasthatjoingmovingLabel_2 = uilabel(app.GridLayout17_2);
            app.InwhichdirectionwasthatjoingmovingLabel_2.Text = 'In which direction was that joing moving?';
            app.WhatjointdidyoufeelmoveLabel_2 = uilabel(app.GridLayout17_2);
            app.WhatjointdidyoufeelmoveLabel_2.Text = 'What joint did you feel move?';
            app.DefinethestrengthvividnessofthemovementsensationLabel_2 = uilabel(app.GridLayout17_2);
            app.DefinethestrengthvividnessofthemovementsensationLabel_2.Text = 'Define the strength, vividness of the movement sensation';
            app.DropDown1_2 = uidropdown(app.GridLayout17_2);
            app.DropDown1_2.Items = {'Yes', 'No'};
            app.DropDown1_2.ItemsData = {'1', '0'};
            app.DropDown1_2.Placeholder = 'Choose Here';
            app.DropDown1_2.Value = '1';
            app.DropDown2_2 = uidropdown(app.GridLayout17_2);
            app.DropDown2_2.Items = {'Yes', 'No'};
            app.DropDown2_2.ItemsData = {'1', '0'};
            app.DropDown2_2.Placeholder = 'Choose Here';
            app.DropDown2_2.Value = '1';
            app.DropDown4_2 = uidropdown(app.GridLayout17_2);
            app.DropDown4_2.Items = {'Flexion', 'Extension'};
            app.DropDown4_2.ItemsData = {'1', '2', '3'};
            app.DropDown4_2.Placeholder = 'Choose Here';
            app.DropDown4_2.Value = '1';
            app.DropDown5_2 = uidropdown(app.GridLayout17_2);
            app.DropDown5_2.Items = {'No Sensation', '1', '2', '3', '4', '5'};
            app.DropDown5_2.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDown5_2.Placeholder = 'Choose Here';
            app.DropDown5_2.Value = '0';
            app.DropDown3_2 = uidropdown(app.GridLayout17_2);
            app.DropDown3_2.Items = {'None'};
            app.DropDown3_2.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.DropDown3_2.Value = '0';
            app.OverwriteLastAnswersButton = uibutton(app.GridLayout3_2, 'push');
            app.OverwriteLastAnswersButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteLastAnswersButtonPushed, true);
            app.OverwriteLastAnswersButton.Enable = 'off';
            app.OverwriteLastAnswersButton.Text = {'Overwrite'; 'Last Answers'};
            app.CyberGloveCalibrationDataPanel = uipanel(app.GridLayout18);
            app.CyberGloveCalibrationDataPanel.Enable = 'off';
            app.CyberGloveCalibrationDataPanel.Title = 'CyberGlove Calibration Data';
            app.GridLayout4 = uigridlayout(app.CyberGloveCalibrationDataPanel);
            app.PingCyberGloveButton = uibutton(app.GridLayout4, 'push');
            app.PingCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @PingCyberGloveButtonPushed, true);
            app.PingCyberGloveButton.Text = {'Ping'; 'CyberGlove'};
            app.SavexlsxPositionButton = uibutton(app.GridLayout4, 'push');
            app.SavexlsxPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavexlsxPositionButtonPushed, true);
            app.SavexlsxPositionButton.Text = {'Save .xlsx'; 'Position'};
            app.PositListBoxLabel = uilabel(app.GridLayout4);
            app.PositListBoxLabel.Text = 'Posit.';
            app.PositListBox = uilistbox(app.GridLayout4);
            app.PositListBox.Items = {'Flex', 'Ext'};
            app.PositListBox.Value = 'Flex';
            app.CyberGloveStreamOFFButton = uibutton(app.GridLayout4, 'state');
            app.CyberGloveStreamOFFButton.ValueChangedFcn = createCallbackFcn(app, @CyberGloveStreamOFFButtonValueChanged, true);
            app.CyberGloveStreamOFFButton.Enable = 'off';
            app.CyberGloveStreamOFFButton.Text = 'CyberGlove Stream OFF';
            app.CyberGloveDataPanel = uipanel(app.GridLayout4);
            app.CyberGloveDataPanel.Tooltip = {'Click on Panel Title to erase Data'};
            app.CyberGloveDataPanel.Title = 'CyberGlove Data';
            app.CyberGloveDataPanel.ButtonDownFcn = createCallbackFcn(app, @CyberGloveDataPanelButtonDown, true);
            app.GridLayout10 = uigridlayout(app.CyberGloveDataPanel);
            app.UITable = uitable(app.GridLayout10);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Phase3AdaptiveThresholdsTab = uitab(app.TabGroup);
            app.Phase3AdaptiveThresholdsTab.Title = 'Phase 3 - Adaptive Thresholds';
            app.Phase3AdaptiveThresholdsTab.ButtonDownFcn = createCallbackFcn(app, @Phase3AdaptiveThresholdsTabButtonDown, true);
            app.GridLayout11 = uigridlayout(app.Phase3AdaptiveThresholdsTab);
            app.SequenceInitializationandExecutionPanel = uipanel(app.GridLayout11);
            app.SequenceInitializationandExecutionPanel.Title = 'Sequence Initialization and Execution';
            app.GridLayout12 = uigridlayout(app.SequenceInitializationandExecutionPanel);
            app.TrialVariableDropDownLabel = uilabel(app.GridLayout12);
            app.TrialVariableDropDownLabel.Text = 'Trial Variable';
            app.TrialVariableDropDown = uidropdown(app.GridLayout12);
            app.TrialVariableDropDown.Items = {'Frequency', 'Torque', 'Impulse_Duration', 'Current_Upper', 'Current_Lower'};
            app.TrialVariableDropDown.Value = 'Frequency';
            app.InfoBox3 = uitextarea(app.GridLayout12);
            app.InfoBox3.Editable = 'off';
            app.CreateTrialDirectoryButton = uibutton(app.GridLayout12, 'push');
            app.CreateTrialDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTrialDirectoryButtonPushed, true);
            app.CreateTrialDirectoryButton.Text = 'Create Trial Directory';
            app.StartSequenceButton = uibutton(app.GridLayout12, 'push');
            app.StartSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @StartSequenceButtonPushed, true);
            app.StartSequenceButton.Text = 'Start Sequence';
            app.LessIntenseButton = uibutton(app.GridLayout12, 'push');
            app.LessIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @LessIntenseButtonPushed, true);
            app.LessIntenseButton.Enable = 'off';
            app.LessIntenseButton.Text = 'Less Intense';
            app.SameIntensityButton = uibutton(app.GridLayout12, 'push');
            app.SameIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @SameIntensityButtonPushed, true);
            app.SameIntensityButton.Enable = 'off';
            app.SameIntensityButton.Text = 'Same Intensity';
            app.ShowDirectoriesButton = uibutton(app.GridLayout12, 'push');
            app.ShowDirectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDirectoriesButtonPushed, true);
            app.ShowDirectoriesButton.Text = 'Show Directories';
            app.MoreIntenseButton = uibutton(app.GridLayout12, 'push');
            app.MoreIntenseButton.ButtonPushedFcn = createCallbackFcn(app, @MoreIntenseButtonPushed, true);
            app.MoreIntenseButton.Enable = 'off';
            app.MoreIntenseButton.Text = 'More Intense';
            app.DeleteLastStepButton = uibutton(app.GridLayout12, 'push');
            app.DeleteLastStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastStepButtonPushed, true);
            app.DeleteLastStepButton.Enable = 'off';
            app.DeleteLastStepButton.Text = 'Delete Last Step';
            app.TrialIdentifierEditFieldLabel = uilabel(app.GridLayout12);
            app.TrialIdentifierEditFieldLabel.Text = 'Trial Identifier';
            app.TrialIdentifierEditField = uieditfield(app.GridLayout12, 'text');
            app.TrialIdentifierEditField.Placeholder = 'Joint ID and Trial#';
            app.EditSubjectsJointsListButton_3 = uibutton(app.GridLayout12, 'push');
            app.EditSubjectsJointsListButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditSubjectsJointsListButtonPushed, true);
            app.EditSubjectsJointsListButton_3.Text = 'Edit Subject''s Joints List';
            app.OutputGraphsPanel = uipanel(app.GridLayout11);
            app.OutputGraphsPanel.Title = 'Output Graphs';
            app.GridLayout14 = uigridlayout(app.OutputGraphsPanel);
            app.UIAxes = uiaxes(app.GridLayout14);
            title(app.UIAxes, 'Adaptive Sequence')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveDataPanel = uipanel(app.GridLayout11);
            app.SaveDataPanel.Title = 'Save Data';
            app.GridLayout15 = uigridlayout(app.SaveDataPanel);
            app.SaveDataButton = uibutton(app.GridLayout15, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.SaveImageButton = uibutton(app.GridLayout15, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SavebothDataandImageButton = uibutton(app.GridLayout15, 'push');
            app.SavebothDataandImageButton.ButtonPushedFcn = createCallbackFcn(app, @SavebothDataandImageButtonPushed, true);
            app.SavebothDataandImageButton.Enable = 'off';
            app.SavebothDataandImageButton.Text = 'Save both Data and Image';
            app.AbortSequenceButton = uibutton(app.GridLayout15, 'push');
            app.AbortSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AbortSequenceButtonPushed, true);
            app.AbortSequenceButton.Enable = 'off';
            app.AbortSequenceButton.Text = 'Abort Sequence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MYKI_PsychoPhysics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end